classdef motor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        UIAxes_preprocess_F          matlab.ui.control.UIAxes
        UIAxes_preprocess_T          matlab.ui.control.UIAxes
        after_Spectrum               matlab.ui.control.UIAxes
        after_wave                   matlab.ui.control.UIAxes
        UIAxes_dataup_T              matlab.ui.control.UIAxes
        UIAxes_dataup_F              matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Label_13                     matlab.ui.control.Label
        loadFile_2                   matlab.ui.control.Button
        loadFile                     matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        n_sample_text                matlab.ui.control.NumericEditField
        Label_14                     matlab.ui.control.Label
        showcutTextArea              matlab.ui.control.TextArea
        Label_8                      matlab.ui.control.Label
        endtime                      matlab.ui.control.NumericEditField
        Label_7                      matlab.ui.control.Label
        starttime                    matlab.ui.control.NumericEditField
        Label_6                      matlab.ui.control.Label
        SaveTrimmedDataButtonPushed  matlab.ui.control.Button
        TrimDataButtonPushed         matlab.ui.control.Button
        cutdataUIAxes_T              matlab.ui.control.UIAxes
        cutdataUIAxes_F              matlab.ui.control.UIAxes
        Tab_2                        matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        DownloadResampledDataButtonPushed  matlab.ui.control.Button
        EditField_ccy                matlab.ui.control.NumericEditField
        Label_9                      matlab.ui.control.Label
        LabelResampleCount           matlab.ui.control.Label
        label_mean                   matlab.ui.control.Label
        RemoveMeanButtonCallback     matlab.ui.control.Button
        label_FF                     matlab.ui.control.Label
        label_min                    matlab.ui.control.Label
        label_max                    matlab.ui.control.Label
        pre_process_Button           matlab.ui.control.Button
        ResampleSignalButtonPushed   matlab.ui.control.StateButton
        Tab_3                        matlab.ui.container.Tab
        ifok                         matlab.ui.control.Button
        get_filter                   matlab.ui.control.Button
        FIRPanel                     matlab.ui.container.Panel
        FIR_Fs                       matlab.ui.control.EditField
        FsHzEditFieldLabel           matlab.ui.control.Label
        FIR_Fp                       matlab.ui.control.EditField
        FpHzEditFieldLabel_2         matlab.ui.control.Label
        FIR_Rp                       matlab.ui.control.NumericEditField
        RpdBEditFieldLabel           matlab.ui.control.Label
        FIR_Rs                       matlab.ui.control.NumericEditField
        RsdBEditFieldLabel           matlab.ui.control.Label
        FIR_choose                   matlab.ui.control.DropDown
        DropDown_7Label              matlab.ui.control.Label
        win_choose                   matlab.ui.control.DropDown
        Label_12                     matlab.ui.control.Label
        FIR_method                   matlab.ui.control.DropDown
        DropDown_5Label              matlab.ui.control.Label
        IIRPanel                     matlab.ui.container.Panel
        IIR_Fs                       matlab.ui.control.EditField
        FpHzEditField_2Label         matlab.ui.control.Label
        IIR_Fp                       matlab.ui.control.EditField
        FpHzEditFieldLabel           matlab.ui.control.Label
        IIR_Rp                       matlab.ui.control.NumericEditField
        RpdBEditField_2Label         matlab.ui.control.Label
        IIR_Rs                       matlab.ui.control.NumericEditField
        FsLabel                      matlab.ui.control.Label
        IIR_method                   matlab.ui.control.DropDown
        Label_11                     matlab.ui.control.Label
        IIR_choose                   matlab.ui.control.DropDown
        DropDown_3Label              matlab.ui.control.Label
        IIR_filterchoose             matlab.ui.control.DropDown
        Label_10                     matlab.ui.control.Label
        Switch                       matlab.ui.control.ToggleSwitch
        Filter_phase                 matlab.ui.control.UIAxes
        Filter_amplitude             matlab.ui.control.UIAxes
        Tab_4                        matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)

        %
        time;
        filter_exist; % 检测是否设计了滤波器
        iir_method_com;
        NN_value;
        dataWithoutMean;
        iir_filterchoose_com;
        iir_choose_com;
        iir_Fp;iir_Fs;iir_Rp;iir_Rs;
        switchok;Bnum;A;
        fir_method_com;
        FS_music
        fir_winchoose_com;
        fir_choose_com;
        fir_Fp;fir_Fs;fir_Rp;fir_Rs;
        hnn;
        draw_t;draw_w;
        voicechange  % 检测是否进行变声设计
        resample_fs % 减采样的频率
        carry_fs%载波频率
        intofilterchoose
        rf%发射信号
        am_b;am_a;am_hn;
        noise_sound
        Zhu_fft;Tian_fft;len_N;
        Lisen_obj
        Property39 % Description
        Property43 % Description
        %
        FilePath % 当前选择的文件路径
        TrimmedData
        TestData
    
        ResampledData
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %视图初始化
            app.FIR_Rp.Enable="off";
            app.FIR_Rs.Enable="off";
            app.FIR_Fp.Enable="off";
            app.FIR_Fs.Enable="off";
            app.FIR_choose.Enable="off";
            app.win_choose.Enable="off";
            app.FIR_method.Enable="off";
        
            % app.blackmann.Enable="off";
            app.rectwinn.Enable="off";
            app.buttordd.Enable="off";
            %变量初始化
            app.time = 0;
            app.filter_exist=0;
            app.iir_method_com=1;
            app.iir_method = 1;
            app.switchok = 1;
            app.Bnum;
            app.A;
            app.iir_filterchoose_com=1;
            app.iir_choose_com=1;
            app.fir_method_com=1;
            app.fir_winchoose_com=1;
            app.fir_choose_com=1;
            app.FS_music=44100;
            app.ButtonGroup.SelectedObject=app.buttordd;
            app.intofilterchoose=0;

        end

        % Button pushed function: loadFile
        function loadFileButtonPushed(app, event)
% 弹出文件选择对话框
[file, path] = uigetfile({'*.xlsx;*.csv;*.txt', 'Test Signal Files (*.xlsx, *.csv, *.txt)'}, 'Select a Test Signal File');
if isequal(file, 0)
    % 未选择文件，显示提示
    app.Label.Text = '未选择文件';
else
    % 更新 Label 显示文件路径
    app.Label.Text = fullfile(path, file);

    % 读取文件内容，根据文件类型自动解析
    [~, ~, ext] = fileparts(file);
    switch lower(ext)
        case '.xlsx'
            data = readtable(fullfile(path, file));
        case '.csv'
            data = readtable(fullfile(path, file));
        case '.txt'
            data = readtable(fullfile(path, file), 'Delimiter', '\t');
        otherwise
            uialert(app.UIFigure, '文件格式不支持', '错误', 'Icon', 'error');
            return;
    end
    
    % 移除含有中文字符的行（示例，假设检查整个表）
    % data = data(~any(varfun(@(x) iscellstr(x) & contains(x, '[^\x00-\x7F]', 'once'), data), 2), :);
    
    % 保存数据到 App 属性中以供后续处理
    app.TestData = data;
    
    % 提取时间、声压和加速度数据
    app.time = data{:, 1};             % 第1列为时间（秒）
    pressure = data{:, 2};         % 第2列为声压（帕）
    acceleration = data{:, 3};     % 第3列为加速度（m/s²）
    
    % 时域波形绘制（在 UIAxes_dataup_T 显示）
    plot(app.UIAxes_dataup_T, app.time, pressure);
    title(app.UIAxes_dataup_T, '时域波形 - 声压');
    xlabel(app.UIAxes_dataup_T, '时间 (s)');
    ylabel(app.UIAxes_dataup_T, '声压 (Pa)');
    
    % 频域波形绘制（在 UIAxes_dataup_F 显示）
    fs = 1 / mean(diff(app.time));  % 采样频率
    L = length(pressure);       % 数据长度
    Y = fft(pressure);          % 快速傅里叶变换
    P2 = abs(Y / L);            % 双侧频谱
    P1 = P2(1:L/2+1);           % 单侧频谱
    P1(2:end-1) = 2 * P1(2:end-1);
    f = fs * (0:(L/2)) / L;     % 频率向量
    
    plot(app.UIAxes_dataup_F, f, P1);
    title(app.UIAxes_dataup_F, '频域波形 - 声压');
    xlabel(app.UIAxes_dataup_F, '频率 (Hz)');
    ylabel(app.UIAxes_dataup_F, '幅值');
end
        end

        % Button pushed function: TrimDataButtonPushed
        function TrimDataButtonPushedPushed(app, event)

    if isempty(app.TestData)
        uialert(app.UIFigure, '未加载文件', '错误', 'Icon', 'error');
        return;
    end
    
    % 获取用户输入的起始和结束时间
    startTime = app.starttime.Value;
    endTime = app.endtime.Value;
    app.NN_value = app.n_sample_text.Value;


    % 使用第一列作为时间列，假设第一列是时间
    app.time = app.TestData{:, 1}; % 第一列是时间
     if endTime == 0 & startTime == 0
        trimmedData = app.TestData(1:NN_value,:);
     else
        if endTime <= startTime
        uialert(app.UIFigure, '结束时间必须大于起始时间', '错误', 'Icon', 'error');
        return;
        end

        trimmedData = app.TestData(app.time >= startTime & app.time <= endTime, :);
     end

    % 检查是否有有效数据
    if isempty(trimmedData)
        uialert(app.UIFigure, '时间范围内无有效数据', '错误', 'Icon', 'error');
        return;
    end
    
    
    % 保存截取的数据到 App 属性中以供后续保存
    app.TrimmedData = trimmedData;
    
    % 统计数据信息
    totalRows = size(trimmedData, 1);        % 数据总行数
    firstColMax = max(trimmedData{:, 1});   % 第一列最大值
    firstColMin = min(trimmedData{:, 1});   % 第一列最小值
    secondColMax = max(trimmedData{:, 2});  % 第二列最大值
    secondColMin = min(trimmedData{:, 2});  % 第二列最小值
    
    % 更新文本区域显示信息
    app.showcutTextArea.Value = sprintf( ...
        '截取后数据的信息：\n总行数：%d\n第一列最大值：%.2f\n第一列最小值：%.2f\n第二列最大值：%.2f\n第二列最小值：%.2f', ...
        totalRows, firstColMax, firstColMin, secondColMax, secondColMin ...
    );
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % 时域谱线：绘制第一列的时域信号
    if NN_value ==0;
    plot(app.cutdataUIAxes_T, app.time(app.time >= startTime & app.time <= endTime), trimmedData{:, 2}); % 假设第二列是信号
    title(app.cutdataUIAxes_T, '时域信号');
    xlabel(app.cutdataUIAxes_T, '时间 (s)');
    ylabel(app.cutdataUIAxes_T, '信号幅度');
    
    % 频域谱线：使用FFT计算频域
    fs = 1 / mean(diff(app.time));  % 采样频率
    signal = trimmedData{:, 2}; % 假设第二列是信号
    N = length(signal);
    f = fs * (0:(N/2))/N;      % 频率轴
    Y = fft(signal);           % 快速傅里叶变换
    P2 = abs(Y/N);             % 双边谱
    P1 = P2(1:N/2+1);          % 单边谱
    P1(2:end-1) = 2*P1(2:end-1); % 双边谱除去第一个频率后倍增幅值
    
    % 绘制频域谱线
    plot(app.cutdataUIAxes_F, f, P1);  
    title(app.cutdataUIAxes_F, '频域谱线');
    xlabel(app.cutdataUIAxes_F, '频率 (Hz)');
    ylabel(app.cutdataUIAxes_F, '幅度');
    else
    plot(app.cutdataUIAxes_T, (1:app.NN_value), trimmedData{:, 2}); % 假设第二列是信号
    title(app.cutdataUIAxes_T, '时域信号');
    xlabel(app.cutdataUIAxes_T, '时间 (s)');
    ylabel(app.cutdataUIAxes_T, '信号幅度');
    
    % 频域谱线：使用FFT计算频域
    fs = 1 / mean(diff(app.time));  % 采样频率
    signal = trimmedData{:, 2}; % 假设第二列是信号
    N = length(signal);
    f = fs * (0:(N/2))/N;      % 频率轴
    Y = fft(signal);           % 快速傅里叶变换
    P2 = abs(Y/N);             % 双边谱
    P1 = P2(1:N/2+1);          % 单边谱
    P1(2:end-1) = 2*P1(2:end-1); % 双边谱除去第一个频率后倍增幅值
    
    % 绘制频域谱线
    plot(app.cutdataUIAxes_F, f, P1);  
    title(app.cutdataUIAxes_F, '频域谱线');
    xlabel(app.cutdataUIAxes_F, '频率 (Hz)');
    ylabel(app.cutdataUIAxes_F, '幅度');
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: SaveTrimmedDataButtonPushed
        function SaveTrimmedDataButtonPushedPushed(app, event)
    if isempty(app.TrimmedData)
        uialert(app.UIFigure, '无截取数据可保存', '错误', 'Icon', 'error');
        return;
    end
    
    % 弹出保存对话框
    [file, path] = uiputfile({'*.xlsx', 'Excel File (*.xlsx)'; '*.csv', 'CSV File (*.csv)'; '*.txt', 'Text File (*.txt)'}, 'Save Trimmed Data');
    if isequal(file, 0)
        return; % 用户取消保存
    end

    % 根据文件格式保存
    [~, ~, ext] = fileparts(file);
    switch lower(ext)
        case '.xlsx'
            writetable(app.TrimmedData, fullfile(path, file));
        case '.csv'
            writetable(app.TrimmedData, fullfile(path, file));
        case '.txt'
            writetable(app.TrimmedData, fullfile(path, file), 'Delimiter', '\t');
        otherwise
            uialert(app.UIFigure, '文件格式不支持', '错误', 'Icon', 'error');
    end
        end

        % Button pushed function: pre_process_Button
        function pre_process_ButtonPushed(app, event)
            % 信号处理按钮的回调函数
    if isempty(app.TestData)
        % 检查是否加载了数据
        uialert(app.UIFigure, '未加载文件，请先上传数据', '错误', 'Icon', 'error');
        return;
    end
    
    % 假设第二列为信号数据
    signalColumn = 2; % 信号列索引
    if size(app.TestData, 2) < signalColumn
        uialert(app.UIFigure, '数据格式不符合要求，至少需要两列，第二列为信号数据', '错误', 'Icon', 'error');
        return;
    end
    
    % 提取信号数据
    signalData = app.TestData{:, signalColumn}; 
    
    % 检查数据中是否存在无效值
    if any(isnan(signalData)) || any(~isnumeric(signalData))
        uialert(app.UIFigure, '信号数据包含无效值，请检查文件内容', '错误', 'Icon', 'error');
        return;
    end
    
    % 计算最大值、最小值和平均值
    maxValue = max(signalData);
    minValue = min(signalData);
    meanValue = mean(signalData);
    FFValue = maxValue-minValue;
    
    % 将计算结果显示在对应的标签上
    app.label_max.Text = sprintf('最大值: %.2f', maxValue);
    app.label_mean.Text = sprintf('平均值: %.2f', meanValue);
    app.label_min.Text = sprintf('最小值: %.2f', minValue);
    app.label_FF.Text = sprintf('峰峰值:%.2f',FFValue);
        end

        % Value changed function: ResampleSignalButtonPushed
        function ResampleSignalButtonPushedValueChanged(app, event)
% 开始重采样按钮回调函数
    % 获取 EditField 中的用户输入值
    inputText = app.EditField_ccy.Value;
    
    % 检查输入值的类型，如果是数字则转换为字符串
    if isnumeric(inputText)
        inputText = num2str(inputText);  % 将数字转为字符串
    end
    
    % 去除前后空格
    inputText = strtrim(inputText);
    
    % 将字符串转换为数字
    resampleFactor = str2double(inputText);
    
    % 校验输入的重采样倍数是否有效
    if isempty(resampleFactor) || isnan(resampleFactor) || resampleFactor <= 0
        % 如果输入无效，显示错误提示
        uialert(app.UIFigure, '请输入有效的重采样倍数（正数）', '错误', 'Icon', 'error');
        return;
    end
    
    % 检查是否有上传的原始数据
    if isempty(app.TestData)
        uialert(app.UIFigure, '未上传信号数据', '错误', 'Icon', 'error');
        return;
    end
    
    % 获取上传数据的时间列和其他列
    app.time = app.TestData{:, 1};  % 假设第一列是时间
    data = app.TestData{:, 2:end};  % 剩余列是其他数据（如压力和加速度等）
    
    % 执行重采样操作，使用 `resample` 或手动插值
    % 假设重采样是根据时间的倍数进行插值
    newTime = app.time(1):resampleFactor:app.time(end);  % 根据重采样倍数生成新的时间向量
    
    % 对每个数据列进行插值
    newData = [];
    for col = 1:size(data, 2)
        newData(:, col) = interp1(app.time, data(:, col), newTime, 'linear', 'extrap');  % 使用线性插值
    end
    
    % 将重采样后的数据保存到 App 中
    app.ResampledData = [newTime(:), newData];  % 将新时间和新数据组合
    
    % 更新UI中的显示，显示重采样后的数据等
    % 例如，可以显示新的数据行数：
    app.LabelResampleCount.Text = ['重采样后的数据行数: ', num2str(size(app.ResampledData, 1))];
    
    % 更新时域图：显示重采样后的第一列数据
    plot(app.UIAxes_preprocess_T, newTime, newData(:, 1));  % 显示时域图
    title(app.UIAxes_preprocess_T, '重采样后的时域信号');
    xlabel(app.UIAxes_preprocess_T, '时间');
    ylabel(app.UIAxes_preprocess_T, '信号幅值');
    
    % 更新频域图：显示重采样后第一列的频域
    N = length(newTime);  % 信号长度
    Fs = 1 / (newTime(2) - newTime(1));  % 采样频率
    freqData = abs(fft(newData(:, 1)));  % 计算 FFT
    f = Fs * (0:(N/2)) / N;  % 频率轴
    P2 = freqData / N;  % 双边频谱
    P1 = P2(1:N/2+1);  % 单边频谱
    P1(2:end-1) = 2 * P1(2:end-1);  % 单边幅值谱

    plot(app.UIAxes_preprocess_F, f, P1);  % 显示频域图
    title(app.UIAxes_preprocess_F, '重采样后的频域信号');
    xlabel(app.UIAxes_preprocess_F, '频率 (Hz)');
    ylabel(app.UIAxes_preprocess_F, '幅度');
            
        end

        % Button pushed function: DownloadResampledDataButtonPushed
        function DownloadResampledDataButtonPushedPushed(app, event)
            % 下载重采样数据按钮的回调函数
    % 检查是否有重采样后的数据
    if isempty(app.ResampledData)
        uialert(app.UIFigure, '没有重采样后的数据可下载', '错误', 'Icon', 'error');
        return;
    end
    
    % 弹出文件选择对话框，允许用户选择保存路径
    [file, path] = uiputfile({'*.xlsx', 'Excel 文件 (*.xlsx)'; ...
                               '*.csv', 'CSV 文件 (*.csv)'}, ...
                               '保存重采样数据为文件');
    
    if isequal(file, 0)  % 如果用户点击取消
        return;
    end
    
    % 获取保存的完整文件路径
    fullFilePath = fullfile(path, file);
    
    % 创建一个表格保存数据
    % 假设 ResampledData 是一个 [时间, 数据] 的矩阵，第一列是时间，剩下的是其他数据
    resampledTable = array2table(app.ResampledData);
    
    % 为表格添加列名
    colNames = {'Time'};
    for i = 2:size(app.ResampledData, 2)
        colNames{end+1} = ['Data_' num2str(i-1)];
    end
    resampledTable.Properties.VariableNames = colNames;
    
    % 根据文件扩展名保存为 Excel 或 CSV
    [~, ~, ext] = fileparts(fullFilePath);
    switch ext
        case '.xlsx'
            % 使用 writetable 保存为 Excel 格式
            writetable(resampledTable, fullFilePath, 'WriteRowNames', true);
            uialert(app.UIFigure, ['数据已保存为 Excel 文件: ' fullFilePath], '成功', 'Icon', 'info');
        case '.csv'
            % 使用 writetable 保存为 CSV 格式
            writetable(resampledTable, fullFilePath);
            uialert(app.UIFigure, ['数据已保存为 CSV 文件: ' fullFilePath], '成功', 'Icon', 'info');
        otherwise
            uialert(app.UIFigure, '不支持的文件格式', '错误', 'Icon', 'error');
    end
        end

        % Button pushed function: RemoveMeanButtonCallback
        function RemoveMeanButtonCallbackPushed(app, event)
            % 按钮的回调函数
    % 检查是否已加载数据
    if isempty(app.TestData)
        uialert(app.UIFigure, '未加载数据，请先上传数据', '错误', 'Icon', 'error');
        return;
    end

    % 获取原始数据（假设数据的第一列为时间，其他列为信号）
    data = app.TestData{:, 2:end};  % 假设第 1 列为时间，去除它

    % 计算每列的均值
    meanData = mean(data, 1);  % 按列计算均值

    % 去均值处理：从每个数据点减去均值
    dataWithoutMean = data - meanData;

    % 更新处理后的数据（保留时间列）
    app.TestData{:, 2:end} = dataWithoutMean;
    
    % % 更新文本区域，显示去均值后的信息
    % app.showcutTextArea.Value = sprintf('去均值后的数据:\n总行数: %d\n第一列最大值: %.4f, 最小值: %.4f\n第二列最大值: %.4f, 最小值: %.4f', ...
    %     size(dataWithoutMean, 1), max(dataWithoutMean(:, 1)), min(dataWithoutMean(:, 1)), ...
    %     max(dataWithoutMean(:, 2)), min(dataWithoutMean(:, 2)));

    % 可以选择更新去均值后数据的图形显示，时域图和频域图
    plot(app.UIAxes_preprocess_T, app.TestData{:, 1}, dataWithoutMean(:, 1));  % 显示第一列信号
    title(app.UIAxes_preprocess_T, '去均值后的时域信号');
    xlabel(app.UIAxes_preprocess_T, '时间');
    ylabel(app.UIAxes_preprocess_T, '信号幅值');

    % 如果需要展示频域图，可以计算频谱
    Fs = 1000;  % 设定采样频率（假设）
    N = length(dataWithoutMean(:, 1));  % 信号长度
    f = Fs * (0:(N/2)) / N;  % 频率轴
    Y = fft(dataWithoutMean(:, 1));  % 计算 FFT
    P2 = abs(Y / N);  % 双边谱
    P1 = P2(1:N/2+1);  % 单边谱
    P1(2:end-1) = 2 * P1(2:end-1);  % 单边幅值谱

    plot(app.UIAxes_preprocess_F, f, P1);  % 显示频域谱线
    title(app.UIAxes_preprocess_F, '去均值后的频域信号');
    xlabel(app.UIAxes_preprocess_F, '频率 (Hz)');
    ylabel(app.UIAxes_preprocess_F, '幅度');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
value = app.Switch.Value;
            if strcmp(value,'IIR')
                app.FIR_Rp.Enable="off";
                app.FIR_Rs.Enable="off";
                app.FIR_Fp.Enable="off";
                app.FIR_Fs.Enable="off";
                app.FIR_choose.Enable="off";
                app.win_choose.Enable="off";
                app.FIR_method.Enable="off";

                app.IIR_Rp.Enable="on";
                app.IIR_Rs.Enable="on";
                app.IIR_Fp.Enable="on";
                app.IIR_Fs.Enable="on";
                app.IIR_choose.Enable="on";
                app.IIR_filterchoose.Enable="on";
                app.IIR_method.Enable="on";
            else
                % app.FIR_Rp.Enable="on";
                % app.FIR_Rs.Enable="on";
                app.FIR_Fp.Enable="on";
                app.FIR_Fs.Enable="on";
                app.FIR_choose.Enable="on";
                app.win_choose.Enable="on";
                app.FIR_method.Enable="on";

                app.IIR_Rp.Enable="off";
                app.IIR_Rs.Enable="off";
                app.IIR_Fp.Enable="off";
                app.IIR_Fs.Enable="off";
                app.IIR_choose.Enable="off";
                app.IIR_filterchoose.Enable="off";
                app.IIR_method.Enable="off";
            end
        end

        % Button pushed function: get_filter
        function get_filterButtonPushed(app, event)
            % 确保 IIR_method 的值是有效的字符串
value = app.IIR_method.Value ;% 确保转换为字符向量

switch (value)
    case '1.脉冲响应不变法'
        app.iir_method_com = 1; % 赋值为标量
    case '2.双线性变换法'
        app.iir_method_com = 2; % 赋值为标量
    otherwise
        error('无效的选择');
end


%%%%%%%%%%%%%%
                     value = app.IIR_choose.Value;
            switch (value)
                case '1.低通'
                    app.iir_choose_com=1;
                case '2.高通'
                    app.iir_choose_com=2;
                case '3.带通'
                    app.iir_choose_com=3;
                    msgbox("带通滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                case '4.带阻'
                    app.iir_choose_com=4;
                    msgbox("带阻滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
            end
%%%%%%%%%%%%%%%
                   value = app.win_choose.Value;
            switch (value)
                case '1.汉宁窗'
                    app.fir_winchoose_com=1;
                case '2.汉明窗'
                    app.fir_winchoose_com=2;
                case '3.布莱克曼窗'
                    app.fir_winchoose_com=3;
                case '4.矩形窗'
                    app.fir_winchoose_com=4;
                case '5.三角型窗'
                    app.fir_winchoose_com=5;
                case '6.凯塞窗'
                    app.fir_winchoose_com=6;
            end


                    value = app.FIR_method.Value;
            switch (value)
                case '1.窗函数设计法'
                    app.fir_method_com=1;
                    app.win_choose.Enable="on";
                    app.FIR_Rp.Enable="off";
                    app.FIR_Rs.Enable="off";
                case '2.等波纹最佳逼近法'
                    app.fir_method_com=2;
                    app.win_choose.Enable="off";
                    app.FIR_Rp.Enable="on";
                    app.FIR_Rs.Enable="on";
            end
            

            value = app.IIR_filterchoose.Value;
            switch (value)
                case '1.巴特沃斯滤波器'
                    app.iir_filterchoose_com=1;
                case '2.切比雪夫Ⅰ型'
                    app.iir_filterchoose_com=2;
                case '3.切比雪夫Ⅱ型'
                    app.iir_filterchoose_com=3;
                case '4.椭圆滤波器'
                    app.iir_filterchoose_com=4;
            end


            value = app.FIR_choose.Value;
            switch (value)
                case '1.低通'
                    app.fir_choose_com=1;
                case '2.高通'
                    app.fir_choose_com=2;
                case '3.带通'
                    app.fir_choose_com=3;
                    switch(app.FIR_method_com)
                        case '1.窗函数设计法'
                            msgbox("带通滤波器的通带上下限截止频率分别写在通带截止频率和阻带截止频率处。","提示",'help');
                        case '2.等波纹最佳逼近法'
                            msgbox("带通滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                    end
                case '4.带阻'
                    app.fir_choose=4;
                    switch(app.FIR_method_com)
                        case '1.窗函数设计法'
                            msgbox("带阻滤波器的通带上下限截止频率分别写在通带截止频率和阻带截止频率处。","提示",'help');
                        case '2.等波纹最佳逼近法'
                            msgbox("带阻滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                    end
            end
            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%
                if isempty(app.TestData)
                % 如果没有上传数据，弹出警告
                uialert(app.UIFigure, '未检测到上传的测试信号，请先上传信号数据！', '提示', 'Icon', 'warning');
                return;
                end
                % time = app.TestData{:, 1};             % 第1列为时间（秒）
                % pressure = app.TestData{:, 2};         % 第2列为声压（帕）
                % acceleration = app.TestData{:, 3};     % 第3列为加速度（m/s²）

                n_sample = size(app.TestData{:, 1}) % 第一列是时间
                n_sample = n_sample(1,1)
                time_max = app.TestData{end,1}-app.TestData{1,1}
                % time_max = time_max(1,1)
                class(time_max);
                app.FS_music =  n_sample/time_max ;% 根据时间间隔计算采样频率 

                yyyyyy1= app.iir_Fp*2/app.FS_music
                yyyyyy2=  app.iir_Fs*2/app.FS_music
                app.iir_Rp
                app.iir_Rs


            %设计滤波器，显示滤波器幅频特性和相频特性
            app.filter_exist = 1;
            % disp(app.iir_Fp)
            % disp(app.IIR_Fs)
            % disp(app.IIR_Rp)
            % disp(app.IIR_Rs)
            % assignin("base","iir_Fp",app.iir_Fp);assignin("base","iir_Fs",app.iir_Fs);
            % assignin("base","iir_Rp",app.iir_Rp);assignin("base","iir_Rs",app.iir_Rs);
            if(strcmp(app.IIR_method.Enable,"on"))
                % app.iir_method = 1;
                % app.iir_filterchoose = 1;
                % app.iir_choose = 1;
                % char(app.iir_method.Value)
                switch(app.iir_method_com)               
                    case 1%脉冲响应不变法
                        switch(app.iir_filterchoose_com)
                            case 1
                                switch(app.iir_choose_com)
                                    case 1
                                        [BU_N1,BU_Wn1]=buttord(app.iir_Fp*2/app.FS_music,(app.iir_Fs*2/app.FS_music),app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N1,BU_Wn1,'low');
                                    case 2
                                        [BU_N2,BU_Wn2]=buttord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N2,BU_Wn2,'high');
                                    case 3
                                        [BU_N3,BU_Wn3]=buttord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N3,BU_Wn3,'bandpass');
                                    case 4
                                        [BU_N4,BU_Wn4]=buttord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N4,BU_Wn4,'stop');
                                end
                            case 2
                                switch(app.iir_choose_com)
                                    case 1
                                        [CH1_N1,CH1_Wn1]=cheb1ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N1,app.iir_Rp,CH1_Wn1,'low');
                                    case 2
                                        [CH1_N2,CH1_Wn2]=cheb1ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N2,app.iir_Rp,CH1_Wn2,'high');
                                    case 3
                                        [CH1_N3,CH1_Wn3]=cheb1ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N3,app.iir_Rp,CH1_Wn3,'bandpass');
                                    case 4
                                        [CH1_N4,CH1_Wn4]=cheb1ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N4,app.iir_Rp,CH1_Wn4,'stop');
                                end
                            case 3
                                switch(app.iir_choose_com)
                                    case 1
                                        [CH2_N1,CH2_Wn1]=cheb2ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N1,app.iir_Rp,CH2_Wn1,'low');
                                    case 2
                                        [CH2_N2,CH2_Wn2]=cheb2ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N2,app.iir_Rp,CH2_Wn2,'high');
                                    case 3
                                        [CH2_N3,CH2_Wn3]=cheb2ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N3,app.iir_Rp,CH2_Wn3,'bandpass');
                                    case 4
                                        [CH2_N4,CH2_Wn4]=cheb2ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N4,app.iir_Rp,CH2_Wn4,'stop');
                                end
                            case 4
                                switch(app.iir_choose_com)
                                    case 1
                                        [EL_N1,EL_Wn1]=ellipord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N1,app.iir_Rp,app.iir_Rs,EL_Wn1,'low');
                                    case 2
                                        [EL_N2,EL_Wn2]=ellipord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N2,app.iir_Rp,app.iir_Rs,EL_Wn2,'high');
                                    case 3
                                        [EL_N3,EL_Wn3]=ellipord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N3,app.iir_Rp,app.iir_Rs,EL_Wn3,'bandpass');
                                    case 4
                                        [EL_N4,EL_Wn4]=ellipord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N4,app.iir_Rp,app.iir_Rs,EL_Wn4,'stop');
                                end
                        end
                    case 2%双线性变换法
                        wp=(2*pi.*app.iir_Fp)./app.FS_music;ws=(2*pi.*app.iir_Fs)./app.FS_music;
                        twp=2.*tan(wp./2).*app.FS_music;tws=2.*tan(ws./2).*app.FS_music;%预畸变
                        assignin("base","iir_twp",twp);assignin("base","iir_tws",tws);
                        switch(app.iir_filterchoose_com)
                            case 1
                                switch(app.iir_choose_com)
                                    case 1
                                        [BU_N1,BU_Wn1]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,'s');
                                        [BU_B1,BU_A1]=butter(BU_N1,BU_Wn1,'low','s');
                                        [b,a]=bilinear(BU_B1,BU_A1,app.FS_music);
                                    case 2
                                        [BU_N2,BU_Wn2]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,'s');
                                        [BU_B2,BU_A2]=butter(BU_N2,BU_Wn2,'high','s');
                                        [b,a]=bilinear(BU_B2,BU_A2,app.FS_music);
                                    case 3
                                        [BU_N3,BU_Wn3]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [BU_B3,BU_A3]=butter(BU_N3,BU_Wn3,'bandpass','s');
                                        [b,a]=bilinear(BU_B3,BU_A3,app.FS_music);
                                    case 4
                                        [BU_N4,BU_Wn4]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [BU_B4,BU_A4]=butter(BU_N4,BU_Wn4,'stop','s');
                                        [b,a]=bilinear(BU_B4,BU_A4,app.FS_music);
                                end
                            case 2
                                switch(app.iir_choose_com)
                                    case 1
                                        [CH1_N1,CH1_Wn1]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B1,CH1_A1]=cheby1(CH1_N1,app.iir_Rp,CH1_Wn1,'low','s');
                                        [b,a]=bilinear(CH1_B1,CH1_A1,app.FS_music);
                                    case 2
                                        [CH1_N2,CH1_Wn2]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B2,CH1_A2]=cheby1(CH1_N2,app.iir_Rp,CH1_Wn2,'high','s');
                                        [b,a]=bilinear(CH1_B2,CH1_A2,app.FS_music);
                                    case 3
                                        [CH1_N3,CH1_Wn3]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B3,CH1_A3]=cheby1(CH1_N3,app.iir_Rp,CH1_Wn3,'bandpass','s');
                                        [b,a]=bilinear(CH1_B3,CH1_A3,app.FS_music);
                                    case 4
                                        [CH1_N4,CH1_Wn4]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B4,CH1_A4]=cheby1(CH1_N4,app.iir_Rp,CH1_Wn4,'stop','s');
                                        [b,a]=bilinear(CH1_B4,CH1_A4,app.FS_music);
                                end
                            case 3
                                switch(app.iir_choose_com)
                                    case 1
                                        [CH2_N1,CH2_Wn1]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B1,CH2_A1]=cheby2(CH2_N1,app.iir_Rp,CH2_Wn1,'low','s');
                                        [b,a]=bilinear(CH2_B1,CH2_A1,app.FS_music);
                                    case 2
                                        [CH2_N2,CH2_Wn2]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B2,CH2_A2]=cheby2(CH2_N2,app.iir_Rp,CH2_Wn2,'high','s');
                                        [b,a]=bilinear(CH2_B2,CH2_A2,app.FS_music);
                                    case 3
                                        [CH2_N3,CH2_Wn3]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B3,CH2_A3]=cheby2(CH2_N3,app.iir_Rp,CH2_Wn3,'bandpass','s');
                                        [b,a]=bilinear(CH2_B3,CH2_A3,app.FS_music);
                                    case 4
                                        [CH2_N4,CH2_Wn4]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B4,CH2_A4]=cheby2(CH2_N4,app.iir_Rp,CH2_Wn4,'stop','s');
                                        [b,a]=bilinear(CH2_B4,CH2_A4,app.FS_music);
                                end
                            case 4
                                switch(app.iir_choose_com)
                                    case 1
                                        [EL_N1,EL_Wn1]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B1,EL_A1]=ellip(EL_N1,app.iir_Rp,app.iir_Rs,EL_Wn1,'low','s');
                                        [b,a]=bilinear(EL_B1,EL_A1,app.FS_music);
                                    case 2
                                        [EL_N2,EL_Wn2]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B2,EL_A2]=ellip(EL_N2,app.iir_Rp,app.iir_Rs,EL_Wn2,'high','s');
                                        [b,a]=bilinear(EL_B2,EL_A2,app.FS_music);
                                    case 3
                                        [EL_N3,EL_Wn3]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B3,EL_A3]=ellip(EL_N3,app.iir_Rp,app.iir_Rs,EL_Wn3,'bandpass','s');
                                        [b,a]=bilinear(EL_B3,EL_A3,app.FS_music);
                                    case 4
                                        [EL_N4,EL_Wn4]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B4,EL_A4]=ellip(EL_N4,app.iir_Rp,app.iir_Rs,EL_Wn4,'stop','s');
                                        [b,a]=bilinear(EL_B4,EL_A4,app.FS_music);
                                end
                        end
                end
                [Hw,w]=freqz(b,a,1024);
                fpic=w./max(w).*app.FS_music./2;
                plot(app.Filter_amplitude,fpic,20*log10(abs(Hw)));xlim(app.Filter_amplitude,[0 app.FS_music/2]);
                plot(app.Filter_phase,fpic,angle(Hw));app.Filter_phase.XTick=app.Filter_amplitude.XTick;
                xlim(app.Filter_phase,[0 app.FS_music/2]);
                app.switchok=1;app.Bnum=b;app.A=a;
            else
                % assignin("base","Nb",Nb);
                switch(app.fir_method_com)
                    case 1%窗函数设计法
                        wc=(app.fir_Fp+app.fir_Fs)/app.FS_music; %理想低通滤波器截止频率(关于 pi 归一化）
                        B=abs(2*pi*(app.fir_Fs-app.fir_Fp)/app.FS_music); %过渡带宽度指标
                        Nb=ceil(11*pi/B); %blackman 窗的长度 N
                        switch(app.fir_winchoose_com)
                            case 1%汉宁窗
                                switch(app.fir_choose_com)
                                    case 1
                                        %msgbox("1");
                                        hn=fir1(Nb-1,wc,"low",hann(Nb));
                                    case 2
                                        % y=hann(Nb);
                                        % assignin("base","hannNb",y);
                                        hn=fir1(Nb-1,wc,"high",hann(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",hann(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",hann(Nb));
                                end
                            case 2%汉明窗
                                switch(app.fir_choose_com)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",hamming(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",hamming(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",hamming(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",hamming(Nb));
                                end
                            case 3%布莱克曼窗
                                switch(app.fir_choose_com)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",blackman(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",blackman(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",blackman(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",blackman(Nb));
                                end
                            case 4%矩形窗
                                switch(app.fir_choose_com)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",rectwin(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",rectwin(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",rectwin(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",rectwin(Nb));
                                end
                            case 5%三角型窗
                                switch(app.fir_choose_com)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",triang(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",triang(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",triang(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",triang(Nb));
                                end
                            case 6%凯赛窗
                                switch(app.fir_choose_com)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",kaiser(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",kaiser(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",kaiser(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",kaiser(Nb));
                                end
                        end

                    case 2% 等波纹最佳逼近
                        switch(app.fir_choose_com)
                            case 1%低通
                                dev=[(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20)];
                                fb=[app.fir_Fp,app.fir_Fs];m=[1,0];% 确定 remezord 函数所需参数 f,m
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 2%高通
                                dev=[10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1)];
                                fb=[app.fir_Fs,app.fir_Fp];m=[0,1];% 确定 remezord 函数所需参数 f,m
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 3%带通
                                dev=[10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20)];
                                fb=[app.fir_Fs(1,1),app.fir_Fp(1,1),app.fir_Fp(1,2),app.fir_Fs(1,2)];m=[0,1,0];
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 4%带阻
                                dev=[(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1)];
                                fb=[app.fir_Fp(1,1),app.fir_Fs(1,1),app.fir_Fs(1,2),app.fir_Fp(1,2)];m=[1,0,1];
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                        end
                end
                Hw=fft(hn,1024); % 求设计的滤波器频率特性
                db_Hw=20*log(abs(Hw))/log(10);
                firw=0:1:1023;firw=firw./1024*app.FS_music;
                plot(app.Filter_amplitude,firw,db_Hw);axis(app.Filter_amplitude,[0,22050,-200,5]);
                plot(app.Filter_phase,firw,angle(Hw));app.Filter_phase.XTick=app.Filter_amplitude.XTick;
                xlim(app.Filter_phase,[0 app.FS_music/2]);
                app.hnn=hn;app.switchok=2;
            end
   
        end

        % Value changed function: IIR_method
        function IIR_methodValueChanged(app, event)

        end

        % Value changed function: IIR_filterchoose
        function IIR_filterchooseValueChanged(app, event)

        end

        % Value changed function: IIR_choose
        function IIR_chooseValueChanged(app, event)

        end

        % Value changed function: FIR_method
        function FIR_methodValueChanged(app, event)

        end

        % Value changed function: win_choose
        function win_chooseValueChanged(app, event)

        end

        % Value changed function: FIR_choose
        function FIR_chooseValueChanged(app, event)
            
        end

        % Value changed function: IIR_Fs
        function IIR_FsValueChanged(app, event)
            value = app.IIR_Fs.Value
            app.iir_Fs=str2num(value)
            
        end

        % Value changed function: IIR_Rs
        function IIR_RsValueChanged(app, event)
            value = app.IIR_Rs.Value
            app.iir_Rs=value
            
        end

        % Value changed function: IIR_Rp
        function IIR_RpValueChanged(app, event)
           value = app.IIR_Rp.Value
           app.iir_Rp=value
            
        end

        % Value changed function: IIR_Fp
        function IIR_FpValueChanged(app, event)
            value = app.IIR_Fp.Value
            app.iir_Fp=str2num(value)
        end

        % Value changed function: FIR_Rp
        function FIR_RpValueChanged(app, event)
            value = app.FIR_Rp.Value;
            app.fir_Rp=str2num(value);
        end

        % Value changed function: FIR_Rs
        function FIR_RsValueChanged(app, event)
            value = app.FIR_Rs.Value;
            app.fir_Rs=str2num(value);
        end

        % Value changed function: FIR_Fs
        function FIR_FsValueChanged(app, event)
            value = app.FIR_Fs.Value;
            app.fir_Fs=str2num(value);
        end

        % Value changed function: FIR_Fp
        function FIR_FpValueChanged(app, event)
            value = app.FIR_Fp.Value;
            app.fir_Fp=str2num(value);
        end

        % Button pushed function: ifok
        function ifokButtonPushed(app, event)
% 从 datatest 加载信号数据
x = app.TestData{:,2}; % 你的信号数据应该存储在 datatest 中
app.time = app.TestData{:, 1};
% 确保 `app.switchok` 和 `app.TestData` 被正确初始化
if isempty(x)
    msgbox('TestData is empty! Please load the test data.');
    return;
end

if app.filter_exist == 1
    % 检查过滤器类型并应用相应处理
    switch (app.switchok)
        case 1 % 使用 IIR 滤波器
            msgbox('Using IIR filter...');
            y = filter(app.Bnum, app.A, x);  % IIR 滤波
        case 2 % 使用 FIR 滤波器
            msgbox('Using FIR filter...');
            y = fftfilt(app.hnn, x, 1000);  % FIR 滤波
        otherwise
            msgbox('No valid filter selected.');
            return;
    end
    size(y)
    % 计算频谱
    Y = fft(y);
    
    % % 播放滤波后的音频数据
    % app.Lisen_obj = audioplayer(y, app.FS_music);
    % play(app.Lisen_obj);

    % 显示时域波形
    plot(app.after_wave, app.time(1:size(y)), y);
    title(app.after_wave, '时域波形 - 声压');
    xlabel(app.after_wave, '时间 (s)');
    ylabel(app.after_wave, '声压 (Pa)');
    app.after_wave.XTick = 0:5:20;


    % 显示频谱图
    plot(app.after_Spectrum, app.time(1:size(y)), abs(Y));
    title(app.after_Spectrum, '频域波形 - 声压');
    xlabel(app.after_Spectrum, '频率 (Hz)');
    ylabel(app.after_Spectrum, '幅值');
else
    msgbox('Filter not initialized. Please set up the filter first.');
end


        end

        % Value changed function: n_sample_text
        function n_sample_textValueChanged(app, event)
            value = app.n_sample_text.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {628, 628};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {480, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.UIAxes_dataup_F = uiaxes(app.GridLayout5);
            title(app.UIAxes_dataup_F, 'Title')
            xlabel(app.UIAxes_dataup_F, 'X')
            ylabel(app.UIAxes_dataup_F, 'Y')
            zlabel(app.UIAxes_dataup_F, 'Z')
            app.UIAxes_dataup_F.Tag = 'UIAxes_dataup_F';
            app.UIAxes_dataup_T = uiaxes(app.GridLayout5);
            title(app.UIAxes_dataup_T, 'Title')
            xlabel(app.UIAxes_dataup_T, 'X')
            ylabel(app.UIAxes_dataup_T, 'Y')
            zlabel(app.UIAxes_dataup_T, 'Z')
            app.UIAxes_dataup_T.Tag = 'UIAxes_dataup_T';
            app.after_wave = uiaxes(app.GridLayout5);
            title(app.after_wave, 'Title')
            xlabel(app.after_wave, 'X')
            ylabel(app.after_wave, 'Y')
            zlabel(app.after_wave, 'Z')
            app.after_wave.Tag = 'after_wave';
            app.after_Spectrum = uiaxes(app.GridLayout5);
            title(app.after_Spectrum, 'Title')
            xlabel(app.after_Spectrum, 'X')
            ylabel(app.after_Spectrum, 'Y')
            zlabel(app.after_Spectrum, 'Z')
            app.after_Spectrum.Tag = 'after_Spectrum';
            app.UIAxes_preprocess_T = uiaxes(app.GridLayout5);
            title(app.UIAxes_preprocess_T, 'Title')
            xlabel(app.UIAxes_preprocess_T, 'X')
            ylabel(app.UIAxes_preprocess_T, 'Y')
            zlabel(app.UIAxes_preprocess_T, 'Z')
            app.UIAxes_preprocess_T.Tag = 'UIAxes_preprocess_T';
            app.UIAxes_preprocess_F = uiaxes(app.GridLayout5);
            title(app.UIAxes_preprocess_F, 'Title')
            xlabel(app.UIAxes_preprocess_F, 'X')
            ylabel(app.UIAxes_preprocess_F, 'Y')
            zlabel(app.UIAxes_preprocess_F, 'Z')
            app.UIAxes_preprocess_F.Tag = 'UIAxes_preprocess_F';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '截取数据';
            app.GridLayout4 = uigridlayout(app.Tab);
            app.cutdataUIAxes_F = uiaxes(app.GridLayout4);
            title(app.cutdataUIAxes_F, '截取数据的时域谱')
            xlabel(app.cutdataUIAxes_F, 't/s')
            ylabel(app.cutdataUIAxes_F, 'Y')
            zlabel(app.cutdataUIAxes_F, 'Z')
            app.cutdataUIAxes_F.Tag = 'cutdataUIAxes';
            app.cutdataUIAxes_T = uiaxes(app.GridLayout4);
            title(app.cutdataUIAxes_T, '截取数据的频域谱')
            xlabel(app.cutdataUIAxes_T, 'w/\pi')
            ylabel(app.cutdataUIAxes_T, 'Y')
            zlabel(app.cutdataUIAxes_T, 'Z')
            app.cutdataUIAxes_T.Tag = 'cutdataUIAxes';
            app.TrimDataButtonPushed = uibutton(app.GridLayout4, 'push');
            app.TrimDataButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @TrimDataButtonPushedPushed, true);
            app.TrimDataButtonPushed.Tag = 'TrimDataButtonPushed';
            app.TrimDataButtonPushed.Text = '开始截取';
            app.SaveTrimmedDataButtonPushed = uibutton(app.GridLayout4, 'push');
            app.SaveTrimmedDataButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @SaveTrimmedDataButtonPushedPushed, true);
            app.SaveTrimmedDataButtonPushed.Tag = 'SaveTrimmedDataButtonPushed';
            app.SaveTrimmedDataButtonPushed.Text = '保存截取';
            app.Label_6 = uilabel(app.GridLayout4);
            app.Label_6.Text = '开始时间/s';
            app.starttime = uieditfield(app.GridLayout4, 'numeric');
            app.starttime.Tag = 'starttime';
            app.Label_7 = uilabel(app.GridLayout4);
            app.Label_7.Text = '结束时间/s';
            app.endtime = uieditfield(app.GridLayout4, 'numeric');
            app.endtime.Tag = 'endtime';
            app.Label_8 = uilabel(app.GridLayout4);
            app.Label_8.Text = '截取信息';
            app.showcutTextArea = uitextarea(app.GridLayout4);
            app.showcutTextArea.Tag = 'showcutTextArea';
            app.Label_14 = uilabel(app.GridLayout4);
            app.Label_14.Text = '按点数截/个 ';
            app.n_sample_text = uieditfield(app.GridLayout4, 'numeric');
            app.n_sample_text.ValueChangedFcn = createCallbackFcn(app, @n_sample_textValueChanged, true);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '信号预处理模块';
            app.GridLayout2 = uigridlayout(app.Tab_2);
            app.ResampleSignalButtonPushed = uibutton(app.GridLayout2, 'state');
            app.ResampleSignalButtonPushed.ValueChangedFcn = createCallbackFcn(app, @ResampleSignalButtonPushedValueChanged, true);
            app.ResampleSignalButtonPushed.Tag = 'ResampleSignalButtonPushed';
            app.ResampleSignalButtonPushed.Text = '开始重采样';
            app.pre_process_Button = uibutton(app.GridLayout2, 'push');
            app.pre_process_Button.ButtonPushedFcn = createCallbackFcn(app, @pre_process_ButtonPushed, true);
            app.pre_process_Button.Tag = 'pre_process_Button';
            app.pre_process_Button.Text = '点击开始预处理';
            app.label_max = uilabel(app.GridLayout2);
            app.label_max.Tag = 'label_max';
            app.label_max.Text = '信号最大值：';
            app.label_min = uilabel(app.GridLayout2);
            app.label_min.Tag = 'label_min';
            app.label_min.Text = '信号最小值：';
            app.label_FF = uilabel(app.GridLayout2);
            app.label_FF.Tag = 'label_FF';
            app.label_FF.Text = '信号峰峰值：';
            app.RemoveMeanButtonCallback = uibutton(app.GridLayout2, 'push');
            app.RemoveMeanButtonCallback.ButtonPushedFcn = createCallbackFcn(app, @RemoveMeanButtonCallbackPushed, true);
            app.RemoveMeanButtonCallback.Tag = 'RemoveMeanButtonCallback';
            app.RemoveMeanButtonCallback.Text = '去均值';
            app.label_mean = uilabel(app.GridLayout2);
            app.label_mean.Tag = 'label_mean';
            app.label_mean.Text = '信号平均值：';
            app.LabelResampleCount = uilabel(app.GridLayout2);
            app.LabelResampleCount.Tag = 'LabelResampleCount';
            app.LabelResampleCount.Text = '';
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = '重采样倍数';
            app.EditField_ccy = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_ccy.Tag = 'EditField_ccy';
            app.DownloadResampledDataButtonPushed = uibutton(app.GridLayout2, 'push');
            app.DownloadResampledDataButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @DownloadResampledDataButtonPushedPushed, true);
            app.DownloadResampledDataButtonPushed.Tag = 'DownloadResampledDataButtonPushed';
            app.DownloadResampledDataButtonPushed.Text = {'下载'; ''; '数据'};
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '预滤波器模块';
            app.Tab_3.Tag = 'iir_method';
            app.Filter_amplitude = uiaxes(app.Tab_3);
            title(app.Filter_amplitude, '滤波器幅频特性')
            xlabel(app.Filter_amplitude, 'f/Hz')
            ylabel(app.Filter_amplitude, '幅值(dB)')
            zlabel(app.Filter_amplitude, 'Z')
            app.Filter_phase = uiaxes(app.Tab_3);
            title(app.Filter_phase, '滤波器相频特性')
            xlabel(app.Filter_phase, 'f/Hz')
            ylabel(app.Filter_phase, '相位(\pi)')
            zlabel(app.Filter_phase, 'Z')
            app.Switch = uiswitch(app.Tab_3, 'toggle');
            app.Switch.Items = {'IIR', 'FIR'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'IIR';
            app.IIRPanel = uipanel(app.Tab_3);
            app.IIRPanel.Title = 'IIR';
            app.Label_10 = uilabel(app.IIRPanel);
            app.Label_10.Text = '滤波器型号';
            app.IIR_filterchoose = uidropdown(app.IIRPanel);
            app.IIR_filterchoose.Items = {'1.巴特沃斯滤波器', '2.切比雪夫Ⅰ型', '3.切比雪夫Ⅱ型', '4.椭圆滤波器'};
            app.IIR_filterchoose.ValueChangedFcn = createCallbackFcn(app, @IIR_filterchooseValueChanged, true);
            app.IIR_filterchoose.Tag = 'IIR_filterchoose';
            app.IIR_filterchoose.Value = '1.巴特沃斯滤波器';
            app.DropDown_3Label = uilabel(app.IIRPanel);
            app.DropDown_3Label.Text = '滤波器性质';
            app.IIR_choose = uidropdown(app.IIRPanel);
            app.IIR_choose.Items = {'1.低通', '2.高通', '3.带通', '4.带阻'};
            app.IIR_choose.ValueChangedFcn = createCallbackFcn(app, @IIR_chooseValueChanged, true);
            app.IIR_choose.Tag = 'IIR_choose';
            app.IIR_choose.Value = '1.低通';
            app.Label_11 = uilabel(app.IIRPanel);
            app.Label_11.Text = '设计方法';
            app.IIR_method = uidropdown(app.IIRPanel);
            app.IIR_method.Items = {'1.脉冲响应不变法', '2.双线性变换法'};
            app.IIR_method.ValueChangedFcn = createCallbackFcn(app, @IIR_methodValueChanged, true);
            app.IIR_method.Tag = 'IIR_method';
            app.IIR_method.Value = '1.脉冲响应不变法';
            app.FsLabel = uilabel(app.IIRPanel);
            app.FsLabel.Text = '阻带最小衰减(Rs/dB)';
            app.IIR_Rs = uieditfield(app.IIRPanel, 'numeric');
            app.IIR_Rs.ValueChangedFcn = createCallbackFcn(app, @IIR_RsValueChanged, true);
            app.RpdBEditField_2Label = uilabel(app.IIRPanel);
            app.RpdBEditField_2Label.Text = '通带最大衰减(Rp/dB)';
            app.IIR_Rp = uieditfield(app.IIRPanel, 'numeric');
            app.IIR_Rp.ValueChangedFcn = createCallbackFcn(app, @IIR_RpValueChanged, true);
            app.FpHzEditFieldLabel = uilabel(app.IIRPanel);
            app.FpHzEditFieldLabel.Text = '通带截止频率(Fp/Hz)';
            app.IIR_Fp = uieditfield(app.IIRPanel, 'text');
            app.IIR_Fp.ValueChangedFcn = createCallbackFcn(app, @IIR_FpValueChanged, true);
            app.IIR_Fp.Value = '0';
            app.FpHzEditField_2Label = uilabel(app.IIRPanel);
            app.FpHzEditField_2Label.Text = '阻带截止频率(Fs/Hz)';
            app.IIR_Fs = uieditfield(app.IIRPanel, 'text');
            app.IIR_Fs.ValueChangedFcn = createCallbackFcn(app, @IIR_FsValueChanged, true);
            app.IIR_Fs.Value = '0';
            app.FIRPanel = uipanel(app.Tab_3);
            app.FIRPanel.Title = 'FIR';
            app.DropDown_5Label = uilabel(app.FIRPanel);
            app.DropDown_5Label.Text = '设计方法';
            app.FIR_method = uidropdown(app.FIRPanel);
            app.FIR_method.Items = {'1.窗函数设计法', '2.等波纹最佳逼近法'};
            app.FIR_method.ValueChangedFcn = createCallbackFcn(app, @FIR_methodValueChanged, true);
            app.FIR_method.Tag = 'FIR_method';
            app.FIR_method.Value = '1.窗函数设计法';
            app.Label_12 = uilabel(app.FIRPanel);
            app.Label_12.Text = '窗类型';
            app.win_choose = uidropdown(app.FIRPanel);
            app.win_choose.Items = {'1.汉宁窗', '2.汉明窗', '3.布莱克曼窗', '4.矩形窗', '5.三角型窗', '6.凯塞窗'};
            app.win_choose.ValueChangedFcn = createCallbackFcn(app, @win_chooseValueChanged, true);
            app.win_choose.Tag = 'win_choose';
            app.win_choose.Value = '1.汉宁窗';
            app.DropDown_7Label = uilabel(app.FIRPanel);
            app.DropDown_7Label.Text = '滤波器性质';
            app.FIR_choose = uidropdown(app.FIRPanel);
            app.FIR_choose.Items = {'1.低通', '2.高通', '3.带通', '4.带阻'};
            app.FIR_choose.ValueChangedFcn = createCallbackFcn(app, @FIR_chooseValueChanged, true);
            app.FIR_choose.Tag = 'FIR_choose';
            app.FIR_choose.Value = '1.低通';
            app.RsdBEditFieldLabel = uilabel(app.FIRPanel);
            app.RsdBEditFieldLabel.Text = '阻带最小衰减(Rs/dB)';
            app.FIR_Rs = uieditfield(app.FIRPanel, 'numeric');
            app.FIR_Rs.ValueChangedFcn = createCallbackFcn(app, @FIR_RsValueChanged, true);
            app.RpdBEditFieldLabel = uilabel(app.FIRPanel);
            app.RpdBEditFieldLabel.Text = '通带最大衰减(Rp/dB)';
            app.FIR_Rp = uieditfield(app.FIRPanel, 'numeric');
            app.FIR_Rp.ValueChangedFcn = createCallbackFcn(app, @FIR_RpValueChanged, true);
            app.FpHzEditFieldLabel_2 = uilabel(app.FIRPanel);
            app.FpHzEditFieldLabel_2.Text = '通带截止频率(Fp/Hz)';
            app.FIR_Fp = uieditfield(app.FIRPanel, 'text');
            app.FIR_Fp.ValueChangedFcn = createCallbackFcn(app, @FIR_FpValueChanged, true);
            app.FIR_Fp.Value = '0';
            app.FsHzEditFieldLabel = uilabel(app.FIRPanel);
            app.FsHzEditFieldLabel.Text = '阻带截止频率(Fs/Hz)';
            app.FIR_Fs = uieditfield(app.FIRPanel, 'text');
            app.FIR_Fs.ValueChangedFcn = createCallbackFcn(app, @FIR_FsValueChanged, true);
            app.FIR_Fs.Value = '0';
            app.get_filter = uibutton(app.Tab_3, 'push');
            app.get_filter.ButtonPushedFcn = createCallbackFcn(app, @get_filterButtonPushed, true);
            app.get_filter.Text = '创建';
            app.ifok = uibutton(app.Tab_3, 'push');
            app.ifok.ButtonPushedFcn = createCallbackFcn(app, @ifokButtonPushed, true);
            app.ifok.Text = '确定参数';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Tab';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '<---电机数据这里上传';
            app.loadFile = uibutton(app.GridLayout3, 'push');
            app.loadFile.ButtonPushedFcn = createCallbackFcn(app, @loadFileButtonPushed, true);
            app.loadFile.Tag = 'SelectFileButtonPushed';
            app.loadFile.Text = '上传数据';
            app.loadFile_2 = uibutton(app.GridLayout3, 'push');
            app.loadFile_2.Text = '上传数据';
            app.Label_13 = uilabel(app.GridLayout3);
            app.Label_13.Text = '<---激励数据这里上传';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end