classdef motor3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        Menu_3                        matlab.ui.container.Menu
        Menu_2                        matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        Label_first                   matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        Button_2                      matlab.ui.control.Button
        showcutTextArea               matlab.ui.control.TextArea
        Label_13                      matlab.ui.control.Label
        n_sample_text                 matlab.ui.control.NumericEditField
        Label_12                      matlab.ui.control.Label
        endtime                       matlab.ui.control.NumericEditField
        Label_11                      matlab.ui.control.Label
        starttime                     matlab.ui.control.NumericEditField
        Label_10                      matlab.ui.control.Label
        SaveTrimmedDataButtonPushed   matlab.ui.control.Button
        TrimDataButtonPushed          matlab.ui.control.Button
        Tab_2                         matlab.ui.container.Tab
        funtion2_TextArea             matlab.ui.control.TextArea
        Label_16                      matlab.ui.control.Label
        text_chuang                   matlab.ui.control.NumericEditField
        Label_15                      matlab.ui.control.Label
        EditField_ccy                 matlab.ui.control.NumericEditField
        Label_14                      matlab.ui.control.Label
        ResampleSignalButtonPushed_2  matlab.ui.control.StateButton
        DownloadResampledDataButtonPushed  matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        RemoveMeanButtonCallback      matlab.ui.control.Button
        ResampleSignalButtonPushed    matlab.ui.control.StateButton
        Tab_3                         matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        ifok                          matlab.ui.control.Button
        get_filter                    matlab.ui.control.Button
        Switch                        matlab.ui.control.ToggleSwitch
        FIRPanel                      matlab.ui.container.Panel
        FIR_Fs                        matlab.ui.control.EditField
        FsHzEditFieldLabel            matlab.ui.control.Label
        FIR_Fp                        matlab.ui.control.EditField
        FpHzEditFieldLabel_2          matlab.ui.control.Label
        FIR_Rp                        matlab.ui.control.NumericEditField
        RpdBEditFieldLabel            matlab.ui.control.Label
        FIR_Rs                        matlab.ui.control.NumericEditField
        RsdBEditFieldLabel            matlab.ui.control.Label
        FIR_choose                    matlab.ui.control.DropDown
        DropDown_7Label               matlab.ui.control.Label
        win_choose                    matlab.ui.control.DropDown
        Label_19                      matlab.ui.control.Label
        FIR_method                    matlab.ui.control.DropDown
        DropDown_5Label               matlab.ui.control.Label
        IIRPanel                      matlab.ui.container.Panel
        IIR_Fs                        matlab.ui.control.EditField
        FpHzEditField_2Label          matlab.ui.control.Label
        IIR_Fp                        matlab.ui.control.EditField
        FpHzEditFieldLabel            matlab.ui.control.Label
        IIR_Rp                        matlab.ui.control.NumericEditField
        RpdBEditField_2Label          matlab.ui.control.Label
        IIR_Rs                        matlab.ui.control.NumericEditField
        FsLabel                       matlab.ui.control.Label
        IIR_method                    matlab.ui.control.DropDown
        Label_18                      matlab.ui.control.Label
        IIR_choose                    matlab.ui.control.DropDown
        DropDown_3Label               matlab.ui.control.Label
        IIR_filterchoose              matlab.ui.control.DropDown
        Label_17                      matlab.ui.control.Label
        Filter_phase                  matlab.ui.control.UIAxes
        Filter_amplitude              matlab.ui.control.UIAxes
        Tab_6                         matlab.ui.container.Tab
        TextArea_2                    matlab.ui.control.TextArea
        Label_20                      matlab.ui.control.Label
        time_show                     matlab.ui.control.Button
        frequence_show                matlab.ui.control.Button
        plotFrequencyAndTransfer      matlab.ui.control.Button
        Button_8                      matlab.ui.control.Button
        Button_7                      matlab.ui.control.Button
        Tab_5                         matlab.ui.container.Tab
        AnalyzeThirdOctaveAWeightedCallback  matlab.ui.control.Button
        EvaluateSoundQualityCallback  matlab.ui.control.Button
        DropDown_2                    matlab.ui.control.DropDown
        DropDown_2Label               matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        Label_21                      matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Label_22                      matlab.ui.control.Label
        UIAxes_RD                     matlab.ui.control.UIAxes
        UIAxes_LD                     matlab.ui.control.UIAxes
        UIAxes_RU                     matlab.ui.control.UIAxes
        UIAxes_LU                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
          

        %
        time;
        filter_exist; % 检测是否设计了滤波器
        iir_method_com;
        NN_value;
        label_REsample;
        label_REmean;
        label_Smooth;
        smoothedData_fin;
        dataWithoutMean_fin;
        time_TestData;
        iir_filterchoose_com;
        labeljisuan;
        labelbeipin;
        TestData_system
        iir_choose_com;
        iir_Fp;iir_Fs;iir_Rp;iir_Rs;
        switchok;Bnum;A;
        fir_method_com;
        FS_music
        fir_winchoose_com;
        fir_choose_com;
        TestDate_system;
        fir_Fp;fir_Fs;fir_Rp;fir_Rs;
        hnn;
        draw_t;draw_w;
        voicechange  % 检测是否进行变声设计
        resample_fs % 减采样的频率
        carry_fs%载波频率
        intofilterchoose
        rf%发射信号
        am_b;am_a;am_hn;
        noise_sound
        Zhu_fft;Tian_fft;len_N;
        Lisen_obj
        Property39 % Description
        Property43 % Description
        %
        FilePath % 当前选择的文件路径
        TrimmedData
        TestData
    
        ResampledData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %视图初始化
            app.FIR_Rp.Enable="off";
            app.FIR_Rs.Enable="off";
            app.FIR_Fp.Enable="off";
            app.FIR_Fs.Enable="off";
            app.FIR_choose.Enable="off";
            app.win_choose.Enable="off";
            app.FIR_method.Enable="off";
            app.TestData_system;
        
            % app.blackmann.Enable="off";
            % app.rectwinn.Enable="off";
            % app.buttordd.Enable="off";
            %变量初始化
            app.time = 0;
            app.filter_exist=0;
            app.iir_method_com=1;
%            app.iir_method = 1;
            app.switchok = 1;
            app.Bnum;
            app.A;
            app.iir_filterchoose_com=1;
            app.iir_choose_com=1;
            app.fir_method_com=1;
            app.fir_winchoose_com=1;
            app.fir_choose_com=1;
            app.FS_music=44100;
            % app.ButtonGroup.SelectedObject=app.buttordd;
            app.intofilterchoose=0;
            app.label_REmean = 0;
            app.label_REsample = 0;
            app.label_Smooth = 0;
            app.labeljisuan = 1;
            app.labelbeipin = 1;
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            % 弹出文件选择对话框
[file, path] = uigetfile({'*.xlsx;*.csv;*.txt', 'Test Signal Files (*.xlsx, *.csv, *.txt)'}, 'Select a Test Signal File');
if isequal(file, 0)
    % 未选择文件，显示提示
    app.Label_first.Text = '未选择文件';
else
    % % 更新 Label 显示文件路径
    app.Label_first.Text = fullfile(path, file);

    % 读取文件内容，根据文件类型自动解析
    [~, ~, ext] = fileparts(file);
    switch lower(ext)
        case '.xlsx'
            data = readtable(fullfile(path, file));
        case '.csv'
            data = readtable(fullfile(path, file));
        case '.txt'
            data = readtable(fullfile(path, file), 'Delimiter', '\t');
        otherwise
            uialert(app.UIFigure, '文件格式不支持', '错误', 'Icon', 'error');
            return;
    end
    
    % 移除含有中文字符的行（示例，假设检查整个表）
    % data = data(~any(varfun(@(x) iscellstr(x) & contains(x, '[^\x00-\x7F]', 'once'), data), 2), :);
    
    % 保存数据到 App 属性中以供后续处理
    app.TestData = data;%testdata表示的是原始的数据，但是后续可能被更新
    
    % 提取时间、声压和加速度数据
    app.time = data{:, 1};             % 第1列为时间（秒）
    pressure = data{:, 2};         % 第2列为声压（帕）
    acceleration = data{:, 3};     % 第3列为加速度（m/s²）
    
    % 时域波形绘制（在 UIAxes_dataup_T 显示）
    plot(app.UIAxes_LU, app.time, pressure);
    title(app.UIAxes_LU, '时域波形 - 声压');
    xlabel(app.UIAxes_LU, '时间 (s)');
    ylabel(app.UIAxes_LU, '声压 (Pa)');
    
    % 频域波形绘制（在 UIAxes_dataup_F 显示）
    fs = 1 / mean(diff(app.time));  % 采样频率
    L = length(pressure);       % 数据长度
    Y = fft(pressure);          % 快速傅里叶变换
    P2 = abs(Y / L);            % 双侧频谱
    P1 = P2(1:L/2+1);           % 单侧频谱
    P1(2:end-1) = 2 * P1(2:end-1);
    f = fs * (0:(L/2)) / L;     % 频率向量
    
    plot(app.UIAxes_RU, f, P1);
    title(app.UIAxes_RU, '频域波形 - 声压');
    xlabel(app.UIAxes_RU, '频率 (Hz)');
    ylabel(app.UIAxes_RU, '幅值');
end
        end

        % Button pushed function: TrimDataButtonPushed
        function TrimDataButtonPushedPushed(app, event)
            
    if isempty(app.TestData)
        uialert(app.UIFigure, '未加载文件', '错误', 'Icon', 'error');
        return;
    end
    
    % 获取用户输入的起始和结束时间
    startTime = app.starttime.Value;
    endTime = app.endtime.Value;
    app.NN_value = app.n_sample_text.Value;
    NN_value=app.NN_value;


    % 使用第一列作为时间列，假设第一列是时间
    app.time = app.TestData{:, 1}; % 第一列是时间
     if endTime == 0 & startTime == 0
        trimmedData = app.TestData(1:NN_value,:);
     else
        if endTime <= startTime
        uialert(app.UIFigure, '结束时间必须大于起始时间', '错误', 'Icon', 'error');
        return;
        end

        trimmedData = app.TestData(app.time >= startTime & app.time <= endTime, :);
     end

    % 检查是否有有效数据
    if isempty(trimmedData)
        uialert(app.UIFigure, '时间范围内无有效数据', '错误', 'Icon', 'error');
        return;
    end
    
    
    % 保存截取的数据到 App 属性中以供后续保存
    app.TrimmedData = trimmedData;
    
    % 统计数据信息
    totalRows = size(trimmedData, 1);        % 数据总行数
    firstColMax = max(trimmedData{:, 1});   % 第一列最大值
    firstColMin = min(trimmedData{:, 1});   % 第一列最小值
    secondColMax = max(trimmedData{:, 2});  % 第二列最大值
    secondColMin = min(trimmedData{:, 2});  % 第二列最小值
    thirdColMax = max(trimmedData{:, 3});  % 第二列最大值
    thirdColMin = min(trimmedData{:, 3});  % 第二列最小值
    
    % 更新文本区域显示信息
    app.showcutTextArea.Value = sprintf( ...
        '截取后数据的信息：\n总行数：%d\n第一列最大值：%.2f\n第一列最小值：%.2f\n第二列最大值：%.2f\n第二列最小值：%.2f\n第三列最大值：%.2f\n第三列最小值：%.2f', ...
        totalRows, firstColMax, firstColMin, secondColMax, secondColMin,thirdColMax,thirdColMin...
        );
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % 时域谱线：绘制第一列的时域信号
    if NN_value ==0;
    plot(app.UIAxes_LD, app.time(app.time >= startTime & app.time <= endTime), trimmedData{:, 2}); % 假设第二列是信号
    title(app.UIAxes_LD, '时域信号');
    xlabel(app.UIAxes_LD, '时间 (s)');
    ylabel(app.UIAxes_LD, '信号幅度');
    
    % 频域谱线：使用FFT计算频域
    fs = 1 / mean(diff(app.time));  % 采样频率
    signal = trimmedData{:, 2}; % 假设第二列是信号
    N = length(signal);
    f = fs * (0:(N/2))/N;      % 频率轴
    Y = fft(signal);           % 快速傅里叶变换
    P2 = abs(Y/N);             % 双边谱
    P1 = P2(1:N/2+1);          % 单边谱
    P1(2:end-1) = 2*P1(2:end-1); % 双边谱除去第一个频率后倍增幅值
    
    % 绘制频域谱线
    plot(app.UIAxes_RD, f, P1);  
    title(app.UIAxes_RD, '频域谱线');
    xlabel(app.UIAxes_RD, '频率 (Hz)');
    ylabel(app.UIAxes_RD, '幅度');
    else
    plot(app.UIAxes_LD, (1:app.NN_value), trimmedData{:, 2}); % 假设第二列是信号
    title(app.UIAxes_LD, '时域信号');
    xlabel(app.UIAxes_LD, '时间 (s)');
    ylabel(app.UIAxes_LD, '信号幅度');
    
    % 频域谱线：使用FFT计算频域
    fs = 1 / mean(diff(app.time));  % 采样频率
    signal = trimmedData{:, 2}; % 假设第二列是信号
    N = length(signal);
    f = fs * (0:(N/2))/N;      % 频率轴
    Y = fft(signal);           % 快速傅里叶变换
    P2 = abs(Y/N);             % 双边谱
    P1 = P2(1:N/2+1);          % 单边谱
    P1(2:end-1) = 2*P1(2:end-1); % 双边谱除去第一个频率后倍增幅值
    
    % 绘制频域谱线
    plot(app.UIAxes_RD, f, P1);  
    title(app.UIAxes_RD, '频域谱线');
    xlabel(app.UIAxes_RD, '频率 (Hz)');
    ylabel(app.UIAxes_RD, '幅度');
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: SaveTrimmedDataButtonPushed
        function SaveTrimmedDataButtonPushedPushed(app, event)
            if isempty(app.TrimmedData)
        uialert(app.UIFigure, '无截取数据可保存', '错误', 'Icon', 'error');
        return;
    end
    
    % 弹出保存对话框
    [file, path] = uiputfile({'*.xlsx', 'Excel File (*.xlsx)'; '*.csv', 'CSV File (*.csv)'; '*.txt', 'Text File (*.txt)'}, 'Save Trimmed Data');
    if isequal(file, 0)
        return; % 用户取消保存
    end

    % 根据文件格式保存
    [~, ~, ext] = fileparts(file);
    switch lower(ext)
        case '.xlsx'
            writetable(app.TrimmedData, fullfile(path, file));
        case '.csv'
            writetable(app.TrimmedData, fullfile(path, file));
        case '.txt'
            writetable(app.TrimmedData, fullfile(path, file), 'Delimiter', '\t');
        otherwise
            uialert(app.UIFigure, '文件格式不支持', '错误', 'Icon', 'error');
    end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.TestData = app.TrimmedData;
            data = app.TestData;
                % 提取时间、声压和加速度数据
    app.time = data{:, 1};             % 第1列为时间（秒）
    pressure = data{:, 2};         % 第2列为声压（帕）
    acceleration = data{:, 3};     % 第3列为加速度（m/s²）
    
    % 时域波形绘制（在 UIAxes_dataup_T 显示）
    plot(app.UIAxes_LU, app.time, pressure);
    title(app.UIAxes_LU, '时域波形 - 声压');
    xlabel(app.UIAxes_LU, '时间 (s)');
    ylabel(app.UIAxes_LU, '声压 (Pa)');
    
    % 频域波形绘制（在 UIAxes_dataup_F 显示）
    fs = 1 / mean(diff(app.time));  % 采样频率
    L = length(pressure);       % 数据长度
    Y = fft(pressure);          % 快速傅里叶变换
    P2 = abs(Y / L);            % 双侧频谱
    P1 = P2(1:L/2+1);           % 单侧频谱
    P1(2:end-1) = 2 * P1(2:end-1);
    f = fs * (0:(L/2)) / L;     % 频率向量
    
    plot(app.UIAxes_RU, f, P1);
    title(app.UIAxes_RU, '频域波形 - 声压');
    xlabel(app.UIAxes_RU, '频率 (Hz)');
    ylabel(app.UIAxes_RU, '幅值');

        end

        % Button pushed function: RemoveMeanButtonCallback
        function RemoveMeanButtonCallbackPushed(app, event)
    % 按钮的回调函数
    app.label_REmean = 1;
         % 按钮的回调函数
    % 检查是否已加载数据
    if isempty(app.TestData)
        uialert(app.UIFigure, '未加载数据，请先上传数据', '错误', 'Icon', 'error');
        return;
    end

    % 获取原始数据（假设数据的第一列为时间，其他列为信号）
    data = app.TestData{:, 2:end};  % 假设第 1 列为时间，去除它

    % 计算每列的均值
    meanData = mean(data, 1);  % 按列计算均值

    % 去均值处理：从每个数据点减去均值
    dataWithoutMean = data - meanData;

    % 更新处理后的数据（保留时间列）
    app.dataWithoutMean_fin = [app.TestData{:,1},dataWithoutMean]
% app.TestData{:, 2:end} = dataWithoutMean;    
    
    % % 更新文本区域，显示去均值后的信息
    % app.showcutTextArea.Value = sprintf('去均值后的数据:\n总行数: %d\n第一列最大值: %.4f, 最小值: %.4f\n第二列最大值: %.4f, 最小值: %.4f', ...
    %     size(dataWithoutMean, 1), max(dataWithoutMean(:, 1)), min(dataWithoutMean(:, 1)), ...
    %     max(dataWithoutMean(:, 2)), min(dataWithoutMean(:, 2)));

    % 可以选择更新去均值后数据的图形显示，时域图和频域图
    plot(app.UIAxes_LD, app.TestData{:, 1}, dataWithoutMean(:, 1));  % 显示第一列信号
    title(app.UIAxes_LD, '去均值后的时域信号');
    xlabel(app.UIAxes_LD, '时间');
    ylabel(app.UIAxes_LD, '信号幅值');

    % 如果需要展示频域图，可以计算频谱
    Fs = 1000;  % 设定采样频率（假设）
    N = length(dataWithoutMean(:, 1));  % 信号长度
    f = Fs * (0:(N/2)) / N;  % 频率轴
    Y = fft(dataWithoutMean(:, 1));  % 计算 FFT
    P2 = abs(Y / N);  % 双边谱
    P1 = P2(1:N/2+1);  % 单边谱
    P1(2:end-1) = 2 * P1(2:end-1);  % 单边幅值谱

    plot(app.UIAxes_RD, f, P1);  % 显示频域谱线
    title(app.UIAxes_RD, '去均值后的频域信号');
    xlabel(app.UIAxes_RD, '频率 (Hz)');
    ylabel(app.UIAxes_RD, '幅度');
        end

        % Value changed function: ResampleSignalButtonPushed
        function ResampleSignalButtonPushedValueChanged(app, event)
       % 开始重采样按钮回调函数
    % 获取 EditField 中的用户输入值
    inputText = app.EditField_ccy.Value;
    app.label_REsample = 1;
    % 检查输入值的类型，如果是数字则转换为字符串
    if isnumeric(inputText)
        inputText = num2str(inputText);  % 将数字转为字符串
    end
    
    % 去除前后空格
    inputText = strtrim(inputText);
    
    % 将字符串转换为数字
    resampleFactor = str2double(inputText);
    
    % 校验输入的重采样倍数是否有效
    if isempty(resampleFactor) || isnan(resampleFactor) || resampleFactor <= 0
        % 如果输入无效，显示错误提示
        uialert(app.UIFigure, '请输入有效的重采样倍数（正数）', '错误', 'Icon', 'error');
        return;
    end
    
    % 检查是否有上传的原始数据
    if isempty(app.TestData)
        uialert(app.UIFigure, '未上传信号数据', '错误', 'Icon', 'error');
        return;
    end
    
    % 获取上传数据的时间列和其他列
    app.time = app.TestData{:, 1};  % 假设第一列是时间
    data = app.TestData{:, 2:end};  % 剩余列是其他数据（如压力和加速度等）
    
    % 执行重采样操作，使用 `resample` 或手动插值
    % 假设重采样是根据时间的倍数进行插值
    newTime = app.time(1):resampleFactor:app.time(end);  % 根据重采样倍数生成新的时间向量
    
    % 对每个数据列进行插值
    newData = [];
    for col = 1:size(data, 2)
        newData(:, col) = interp1(app.time, data(:, col), newTime, 'linear', 'extrap');  % 使用线性插值
    end
    
    % 将重采样后的数据保存到 App 中
    app.ResampledData = [newTime(:), newData]  % 将新时间和新数据组合
    number = num2str(size(app.ResampledData, 1));
    
    % 更新UI中的显示，显示重采样后的数据等
    % 例如，可以显示新的数据行数：
    app.funtion2_TextArea.Value = ['重采样后的数据行数: ', num2str(size(app.ResampledData, 1))];
    app.funtion2_TextArea.Value = sprintf( ...
        '重采样后的数据行数: %d',number );
    % 更新时域图：显示重采样后的第一列数据
    plot(app.UIAxes_LD, newTime, newData(:, 1));  % 显示时域图
    title(app.UIAxes_LD, '重采样后的时域信号');
    xlabel(app.UIAxes_LD, '时间');
    ylabel(app.UIAxes_LD, '信号幅值');
    
    % 更新频域图：显示重采样后第一列的频域
    N = length(newTime);  % 信号长度
    Fs = 1 / (newTime(2) - newTime(1));  % 采样频率
    freqData = abs(fft(newData(:, 1)));  % 计算 FFT
    f = Fs * (0:(N/2)) / N;  % 频率轴
    P2 = freqData / N;  % 双边频谱
    P1 = P2(1:N/2+1);  % 单边频谱
    P1(2:end-1) = 2 * P1(2:end-1);  % 单边幅值谱

    plot(app.UIAxes_RD, f, P1);  % 显示频域图
    title(app.UIAxes_RD, '重采样后的频域信号');
    xlabel(app.UIAxes_RD, '频率 (Hz)');
    ylabel(app.UIAxes_RD, '幅度');
            
        end

        % Button pushed function: DownloadResampledDataButtonPushed
        function DownloadResampledDataButtonPushedPushed(app, event)
              % 下载重采样数据按钮的回调函数
    % 检查是否有重采样后的数据
    if isempty(app.ResampledData)
        uialert(app.UIFigure, '没有重采样后的数据可下载', '错误', 'Icon', 'error');
        return;
    end
    
    % 弹出文件选择对话框，允许用户选择保存路径
    [file, path] = uiputfile({'*.xlsx', 'Excel 文件 (*.xlsx)'; ...
                               '*.csv', 'CSV 文件 (*.csv)'}, ...
                               '保存重采样数据为文件');
    
    if isequal(file, 0)  % 如果用户点击取消
        return;
    end
    
    % 获取保存的完整文件路径
    fullFilePath = fullfile(path, file);
    
    % 创建一个表格保存数据
    % 假设 ResampledData 是一个 [时间, 数据] 的矩阵，第一列是时间，剩下的是其他数据
    resampledTable = array2table(app.ResampledData);
    
    % 为表格添加列名
    colNames = {'Time'};
    for i = 2:size(app.ResampledData, 2)
        colNames{end+1} = ['Data_' num2str(i-1)];
    end
    resampledTable.Properties.VariableNames = colNames;
    
    % 根据文件扩展名保存为 Excel 或 CSV
    [~, ~, ext] = fileparts(fullFilePath);
    switch ext
        case '.xlsx'
            % 使用 writetable 保存为 Excel 格式
            writetable(resampledTable, fullFilePath, 'WriteRowNames', true);
            uialert(app.UIFigure, ['数据已保存为 Excel 文件: ' fullFilePath], '成功', 'Icon', 'info');
        case '.csv'
            % 使用 writetable 保存为 CSV 格式
            writetable(resampledTable, fullFilePath);
            uialert(app.UIFigure, ['数据已保存为 CSV 文件: ' fullFilePath], '成功', 'Icon', 'info');
        otherwise
            uialert(app.UIFigure, '不支持的文件格式', '错误', 'Icon', 'error');
    end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
value = app.Switch.Value;
            if strcmp(value,'IIR')
                app.FIR_Rp.Enable="off";
                app.FIR_Rs.Enable="off";
                app.FIR_Fp.Enable="off";
                app.FIR_Fs.Enable="off";
                app.FIR_choose.Enable="off";
                app.win_choose.Enable="off";
                app.FIR_method.Enable="off";

                app.IIR_Rp.Enable="on";
                app.IIR_Rs.Enable="on";
                app.IIR_Fp.Enable="on";
                app.IIR_Fs.Enable="on";
                app.IIR_choose.Enable="on";
                app.IIR_filterchoose.Enable="on";
                app.IIR_method.Enable="on";
            else
                % app.FIR_Rp.Enable="on";
                % app.FIR_Rs.Enable="on";
                app.FIR_Fp.Enable="on";
                app.FIR_Fs.Enable="on";
                app.FIR_choose.Enable="on";
                app.win_choose.Enable="on";
                app.FIR_method.Enable="on";

                app.IIR_Rp.Enable="off";
                app.IIR_Rs.Enable="off";
                app.IIR_Fp.Enable="off";
                app.IIR_Fs.Enable="off";
                app.IIR_choose.Enable="off";
                app.IIR_filterchoose.Enable="off";
                app.IIR_method.Enable="off";
            end
        end

        % Button pushed function: get_filter
        function get_filterButtonPushed(app, event)
 % 确保 IIR_method 的值是有效的字符串
value = app.IIR_method.Value ;% 确保转换为字符向量

switch (value)
    case '1.脉冲响应不变法'
        app.iir_method_com = 1; % 赋值为标量
    case '2.双线性变换法'
        app.iir_method_com = 2; % 赋值为标量
    otherwise
        error('无效的选择');
end


%%%%%%%%%%%%%%
                     value = app.IIR_choose.Value;
            switch (value)
                case '1.低通'
                    app.iir_choose_com=1;
                case '2.高通'
                    app.iir_choose_com=2;
                case '3.带通'
                    app.iir_choose_com=3;
                    msgbox("带通滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                case '4.带阻'
                    app.iir_choose_com=4;
                    msgbox("带阻滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
            end
%%%%%%%%%%%%%%%
                   value = app.win_choose.Value;
            switch (value)
                case '1.汉宁窗'
                    app.fir_winchoose_com=1;
                case '2.汉明窗'
                    app.fir_winchoose_com=2;
                case '3.布莱克曼窗'
                    app.fir_winchoose_com=3;
                case '4.矩形窗'
                    app.fir_winchoose_com=4;
                case '5.三角型窗'
                    app.fir_winchoose_com=5;
                case '6.凯塞窗'
                    app.fir_winchoose_com=6;
            end


                    value = app.FIR_method.Value;
            switch (value)
                case '1.窗函数设计法'
                    app.fir_method_com=1;
                    app.win_choose.Enable="on";
                    app.FIR_Rp.Enable="off";
                    app.FIR_Rs.Enable="off";
                case '2.等波纹最佳逼近法'
                    app.fir_method_com=2;
                    app.win_choose.Enable="off";
                    app.FIR_Rp.Enable="on";
                    app.FIR_Rs.Enable="on";
            end
            

            value = app.IIR_filterchoose.Value;
            switch (value)
                case '1.巴特沃斯滤波器'
                    app.iir_filterchoose_com=1;
                case '2.切比雪夫Ⅰ型'
                    app.iir_filterchoose_com=2;
                case '3.切比雪夫Ⅱ型'
                    app.iir_filterchoose_com=3;
                case '4.椭圆滤波器'
                    app.iir_filterchoose_com=4;
            end


            value = app.FIR_choose.Value;
            switch (value)
                case '1.低通'
                    app.fir_choose_com=1;
                case '2.高通'
                    app.fir_choose_com=2;
                case '3.带通'
                    app.fir_choose_com=3;
                    switch(app.FIR_method_com)
                        case '1.窗函数设计法'
                            msgbox("带通滤波器的通带上下限截止频率分别写在通带截止频率和阻带截止频率处。","提示",'help');
                        case '2.等波纹最佳逼近法'
                            msgbox("带通滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                    end
                case '4.带阻'
                    app.fir_choose=4;
                    switch(app.FIR_method_com)
                        case '1.窗函数设计法'
                            msgbox("带阻滤波器的通带上下限截止频率分别写在通带截止频率和阻带截止频率处。","提示",'help');
                        case '2.等波纹最佳逼近法'
                            msgbox("带阻滤波器的通带上下限截止频率用空格隔开，阻带同上。","提示",'help');
                    end
            end
            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%
                if isempty(app.TestData)
                % 如果没有上传数据，弹出警告
                uialert(app.UIFigure, '未检测到上传的测试信号，请先上传信号数据！', '提示', 'Icon', 'warning');
                return;
                end
                % time = app.TestData{:, 1};             % 第1列为时间（秒）
                % pressure = app.TestData{:, 2};         % 第2列为声压（帕）
                % acceleration = app.TestData{:, 3};     % 第3列为加速度（m/s²）

                n_sample = size(app.TestData{:, 1}) % 第一列是时间
                n_sample = n_sample(1,1)
                time_max = app.TestData{end,1}-app.TestData{1,1}
                % time_max = time_max(1,1)
                class(time_max);
                app.FS_music =  n_sample/time_max ;% 根据时间间隔计算采样频率 

                yyyyyy1= app.iir_Fp*2/app.FS_music
                yyyyyy2=  app.iir_Fs*2/app.FS_music
                app.iir_Rp
                app.iir_Rs


            %设计滤波器，显示滤波器幅频特性和相频特性
            app.filter_exist = 1;
            % disp(app.iir_Fp)
            % disp(app.IIR_Fs)
            % disp(app.IIR_Rp)
            % disp(app.IIR_Rs)
            % assignin("base","iir_Fp",app.iir_Fp);assignin("base","iir_Fs",app.iir_Fs);
            % assignin("base","iir_Rp",app.iir_Rp);assignin("base","iir_Rs",app.iir_Rs);
            if(strcmp(app.IIR_method.Enable,"on"))
                % app.iir_method = 1;
                % app.iir_filterchoose = 1;
                % app.iir_choose = 1;
                % char(app.iir_method.Value)
                switch(app.iir_method_com)               
                    case 1%脉冲响应不变法
                        switch(app.iir_filterchoose_com)
                            case 1
                                switch(app.iir_choose_com)
                                    case 1
                                        [BU_N1,BU_Wn1]=buttord(app.iir_Fp*2/app.FS_music,(app.iir_Fs*2/app.FS_music),app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N1,BU_Wn1,'low');
                                    case 2
                                        [BU_N2,BU_Wn2]=buttord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N2,BU_Wn2,'high');
                                    case 3
                                        [BU_N3,BU_Wn3]=buttord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N3,BU_Wn3,'bandpass');
                                    case 4
                                        [BU_N4,BU_Wn4]=buttord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=butter(BU_N4,BU_Wn4,'stop');
                                end
                            case 2
                                switch(app.iir_choose_com)
                                    case 1
                                        [CH1_N1,CH1_Wn1]=cheb1ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N1,app.iir_Rp,CH1_Wn1,'low');
                                    case 2
                                        [CH1_N2,CH1_Wn2]=cheb1ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N2,app.iir_Rp,CH1_Wn2,'high');
                                    case 3
                                        [CH1_N3,CH1_Wn3]=cheb1ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N3,app.iir_Rp,CH1_Wn3,'bandpass');
                                    case 4
                                        [CH1_N4,CH1_Wn4]=cheb1ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby1(CH1_N4,app.iir_Rp,CH1_Wn4,'stop');
                                end
                            case 3
                                switch(app.iir_choose_com)
                                    case 1
                                        [CH2_N1,CH2_Wn1]=cheb2ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N1,app.iir_Rp,CH2_Wn1,'low');
                                    case 2
                                        [CH2_N2,CH2_Wn2]=cheb2ord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N2,app.iir_Rp,CH2_Wn2,'high');
                                    case 3
                                        [CH2_N3,CH2_Wn3]=cheb2ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N3,app.iir_Rp,CH2_Wn3,'bandpass');
                                    case 4
                                        [CH2_N4,CH2_Wn4]=cheb2ord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=cheby2(CH2_N4,app.iir_Rp,CH2_Wn4,'stop');
                                end
                            case 4
                                switch(app.iir_choose_com)
                                    case 1
                                        [EL_N1,EL_Wn1]=ellipord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N1,app.iir_Rp,app.iir_Rs,EL_Wn1,'low');
                                    case 2
                                        [EL_N2,EL_Wn2]=ellipord(app.iir_Fp*2/app.FS_music,app.iir_Fs*2/app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N2,app.iir_Rp,app.iir_Rs,EL_Wn2,'high');
                                    case 3
                                        [EL_N3,EL_Wn3]=ellipord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N3,app.iir_Rp,app.iir_Rs,EL_Wn3,'bandpass');
                                    case 4
                                        [EL_N4,EL_Wn4]=ellipord(app.iir_Fp.*2./app.FS_music,app.iir_Fs.*2./app.FS_music,app.iir_Rp,app.iir_Rs);
                                        [b,a]=ellip(EL_N4,app.iir_Rp,app.iir_Rs,EL_Wn4,'stop');
                                end
                        end
                    case 2%双线性变换法
                        wp=(2*pi.*app.iir_Fp)./app.FS_music;ws=(2*pi.*app.iir_Fs)./app.FS_music;
                        twp=2.*tan(wp./2).*app.FS_music;tws=2.*tan(ws./2).*app.FS_music;%预畸变
                        assignin("base","iir_twp",twp);assignin("base","iir_tws",tws);
                        switch(app.iir_filterchoose_com)
                            case 1
                                switch(app.iir_choose_com)
                                    case 1
                                        [BU_N1,BU_Wn1]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,'s');
                                        [BU_B1,BU_A1]=butter(BU_N1,BU_Wn1,'low','s');
                                        [b,a]=bilinear(BU_B1,BU_A1,app.FS_music);
                                    case 2
                                        [BU_N2,BU_Wn2]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,'s');
                                        [BU_B2,BU_A2]=butter(BU_N2,BU_Wn2,'high','s');
                                        [b,a]=bilinear(BU_B2,BU_A2,app.FS_music);
                                    case 3
                                        [BU_N3,BU_Wn3]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [BU_B3,BU_A3]=butter(BU_N3,BU_Wn3,'bandpass','s');
                                        [b,a]=bilinear(BU_B3,BU_A3,app.FS_music);
                                    case 4
                                        [BU_N4,BU_Wn4]=buttord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [BU_B4,BU_A4]=butter(BU_N4,BU_Wn4,'stop','s');
                                        [b,a]=bilinear(BU_B4,BU_A4,app.FS_music);
                                end
                            case 2
                                switch(app.iir_choose_com)
                                    case 1
                                        [CH1_N1,CH1_Wn1]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B1,CH1_A1]=cheby1(CH1_N1,app.iir_Rp,CH1_Wn1,'low','s');
                                        [b,a]=bilinear(CH1_B1,CH1_A1,app.FS_music);
                                    case 2
                                        [CH1_N2,CH1_Wn2]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B2,CH1_A2]=cheby1(CH1_N2,app.iir_Rp,CH1_Wn2,'high','s');
                                        [b,a]=bilinear(CH1_B2,CH1_A2,app.FS_music);
                                    case 3
                                        [CH1_N3,CH1_Wn3]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B3,CH1_A3]=cheby1(CH1_N3,app.iir_Rp,CH1_Wn3,'bandpass','s');
                                        [b,a]=bilinear(CH1_B3,CH1_A3,app.FS_music);
                                    case 4
                                        [CH1_N4,CH1_Wn4]=cheb1ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH1_B4,CH1_A4]=cheby1(CH1_N4,app.iir_Rp,CH1_Wn4,'stop','s');
                                        [b,a]=bilinear(CH1_B4,CH1_A4,app.FS_music);
                                end
                            case 3
                                switch(app.iir_choose_com)
                                    case 1
                                        [CH2_N1,CH2_Wn1]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B1,CH2_A1]=cheby2(CH2_N1,app.iir_Rp,CH2_Wn1,'low','s');
                                        [b,a]=bilinear(CH2_B1,CH2_A1,app.FS_music);
                                    case 2
                                        [CH2_N2,CH2_Wn2]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B2,CH2_A2]=cheby2(CH2_N2,app.iir_Rp,CH2_Wn2,'high','s');
                                        [b,a]=bilinear(CH2_B2,CH2_A2,app.FS_music);
                                    case 3
                                        [CH2_N3,CH2_Wn3]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B3,CH2_A3]=cheby2(CH2_N3,app.iir_Rp,CH2_Wn3,'bandpass','s');
                                        [b,a]=bilinear(CH2_B3,CH2_A3,app.FS_music);
                                    case 4
                                        [CH2_N4,CH2_Wn4]=cheb2ord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [CH2_B4,CH2_A4]=cheby2(CH2_N4,app.iir_Rp,CH2_Wn4,'stop','s');
                                        [b,a]=bilinear(CH2_B4,CH2_A4,app.FS_music);
                                end
                            case 4
                                switch(app.iir_choose_com)
                                    case 1
                                        [EL_N1,EL_Wn1]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B1,EL_A1]=ellip(EL_N1,app.iir_Rp,app.iir_Rs,EL_Wn1,'low','s');
                                        [b,a]=bilinear(EL_B1,EL_A1,app.FS_music);
                                    case 2
                                        [EL_N2,EL_Wn2]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B2,EL_A2]=ellip(EL_N2,app.iir_Rp,app.iir_Rs,EL_Wn2,'high','s');
                                        [b,a]=bilinear(EL_B2,EL_A2,app.FS_music);
                                    case 3
                                        [EL_N3,EL_Wn3]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B3,EL_A3]=ellip(EL_N3,app.iir_Rp,app.iir_Rs,EL_Wn3,'bandpass','s');
                                        [b,a]=bilinear(EL_B3,EL_A3,app.FS_music);
                                    case 4
                                        [EL_N4,EL_Wn4]=ellipord(twp,tws,app.iir_Rp,app.iir_Rs,"s");
                                        [EL_B4,EL_A4]=ellip(EL_N4,app.iir_Rp,app.iir_Rs,EL_Wn4,'stop','s');
                                        [b,a]=bilinear(EL_B4,EL_A4,app.FS_music);
                                end
                        end
                end
                [Hw,w]=freqz(b,a,1024);
                fpic=w./max(w).*app.FS_music./2;
                plot(app.Filter_amplitude,fpic,20*log10(abs(Hw)));xlim(app.Filter_amplitude,[0 app.FS_music/2]);
                plot(app.Filter_phase,fpic,angle(Hw));app.Filter_phase.XTick=app.Filter_amplitude.XTick;
                xlim(app.Filter_phase,[0 app.FS_music/2]);
                app.switchok=1;app.Bnum=b;app.A=a;
            else
                % assignin("base","Nb",Nb);
                switch(app.fir_method_com)
                    case 1%窗函数设计法
                        wc=(app.fir_Fp+app.fir_Fs)/app.FS_music; %理想低通滤波器截止频率(关于 pi 归一化）
                        B=abs(2*pi*(app.fir_Fs-app.fir_Fp)/app.FS_music); %过渡带宽度指标
                        Nb=ceil(11*pi/B); %blackman 窗的长度 N
                        switch(app.fir_winchoose_com)
                            case 1%汉宁窗
                                switch(app.fir_choose_com)
                                    case 1
                                        %msgbox("1");
                                        hn=fir1(Nb-1,wc,"low",hann(Nb));
                                    case 2
                                        % y=hann(Nb);
                                        % assignin("base","hannNb",y);
                                        hn=fir1(Nb-1,wc,"high",hann(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",hann(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",hann(Nb));
                                end
                            case 2%汉明窗
                                switch(app.fir_choose_com)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",hamming(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",hamming(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",hamming(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",hamming(Nb));
                                end
                            case 3%布莱克曼窗
                                switch(app.fir_choose_com)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",blackman(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",blackman(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",blackman(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",blackman(Nb));
                                end
                            case 4%矩形窗
                                switch(app.fir_choose_com)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",rectwin(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",rectwin(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",rectwin(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",rectwin(Nb));
                                end
                            case 5%三角型窗
                                switch(app.fir_choose_com)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",triang(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",triang(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",triang(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",triang(Nb));
                                end
                            case 6%凯赛窗
                                switch(app.fir_choose_com)
                                    case 1
                                        hn=fir1(Nb-1,wc,"low",kaiser(Nb));
                                    case 2
                                        hn=fir1(Nb-1,wc,"high",kaiser(Nb));
                                    case 3
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"bandpass",kaiser(Nb));
                                    case 4
                                        wc=[app.fir_Fp,app.fir_Fs]./app.FS_music;
                                        hn=fir1(Nb-1,wc,"stop",kaiser(Nb));
                                end
                        end

                    case 2% 等波纹最佳逼近
                        switch(app.fir_choose_com)
                            case 1%低通
                                dev=[(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20)];
                                fb=[app.fir_Fp,app.fir_Fs];m=[1,0];% 确定 remezord 函数所需参数 f,m
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 2%高通
                                dev=[10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1)];
                                fb=[app.fir_Fs,app.fir_Fp];m=[0,1];% 确定 remezord 函数所需参数 f,m
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 3%带通
                                dev=[10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20)];
                                fb=[app.fir_Fs(1,1),app.fir_Fp(1,1),app.fir_Fp(1,2),app.fir_Fs(1,2)];m=[0,1,0];
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                            case 4%带阻
                                dev=[(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1),10^(-app.fir_Rs/20),(10^(app.fir_Rp/20)-1)/(10^(app.fir_Rp/20)+1)];
                                fb=[app.fir_Fp(1,1),app.fir_Fs(1,1),app.fir_Fs(1,2),app.fir_Fp(1,2)];m=[1,0,1];
                                [Ne,fo,mo,W]=remezord(fb,m,dev,app.FS_music); % 确定 remez 函数所需参数
                                hn=remez(Ne,fo,mo,W); % 调用 remez 函数进行设计
                        end
                end
                Hw=fft(hn,1024); % 求设计的滤波器频率特性
                db_Hw=20*log(abs(Hw))/log(10);
                firw=0:1:1023;firw=firw./1024*app.FS_music;
                plot(app.Filter_amplitude,firw,db_Hw);axis(app.Filter_amplitude,[0,22050,-200,5]);
                plot(app.Filter_phase,firw,angle(Hw));app.Filter_phase.XTick=app.Filter_amplitude.XTick;
                xlim(app.Filter_phase,[0 app.FS_music/2]);
                app.hnn=hn;app.switchok=2;
            end
        end

        % Button pushed function: ifok
        function ifokButtonPushed(app, event)
            % 从 datatest 加载信号数据
x = app.TestData{:,2}; % 你的信号数据应该存储在 datatest 中
app.time = app.TestData{:, 1};
% 确保 `app.switchok` 和 `app.TestData` 被正确初始化
if isempty(x)
    msgbox('TestData is empty! Please load the test data.');
    return;
end

if app.filter_exist == 1
    % 检查过滤器类型并应用相应处理
    switch (app.switchok)
        case 1 % 使用 IIR 滤波器
            msgbox('Using IIR filter...');
            y = filter(app.Bnum, app.A, x);  % IIR 滤波
        case 2 % 使用 FIR 滤波器
            msgbox('Using FIR filter...');
            y = fftfilt(app.hnn, x, 1000);  % FIR 滤波
        otherwise
            msgbox('No valid filter selected.');
            return;
    end
    size(y)
    % 计算频谱
    Y = fft(y);
    
    % % 播放滤波后的音频数据
    % app.Lisen_obj = audioplayer(y, app.FS_music);
    % play(app.Lisen_obj);

    % 显示时域波形
    plot(app.UIAxes_LD, app.time(1:size(y)), y);
    title(app.UIAxes_LD, '时域波形 - 声压');
    xlabel(app.UIAxes_LD, '时间 (s)');
    ylabel(app.UIAxes_LD, '声压 (Pa)');


    % 显示频谱图
    plot(app.UIAxes_RD, app.time(1:size(y)), abs(Y));
    title(app.UIAxes_RD, '频域波形 - 声压');
    xlabel(app.UIAxes_RD, '频率 (Hz)');
    ylabel(app.UIAxes_RD, '幅值');
    app.UIAxes_RD.XTick = 0:5:200;
else
    msgbox('Filter not initialized. Please set up the filter first.');
end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
    num1 = app.label_REsample;
    num2 = app.label_REmean;
    num3 = app.label_Smooth;
         
    if num1 == 1
    app.TestData = app.ResampledData;
    size(app.TestData);
    data = app.TestData;
    size(data)
                % 提取时间、声压和加速度数据
    app.time = data(:, 1);             % 第1列为时间（秒）
    pressure = data(:, 2);         % 第2列为声压（帕）
    acceleration = data(:, 3);     % 第3列为加速度（m/s²）
    
    % 时域波形绘制（在 UIAxes_dataup_T 显示）
    plot(app.UIAxes_LU, app.time, pressure);
    title(app.UIAxes_LU, '时域波形 - 声压');
    xlabel(app.UIAxes_LU, '时间 (s)');
    ylabel(app.UIAxes_LU, '声压 (Pa)');
    
    % 频域波形绘制（在 UIAxes_dataup_F 显示）
    fs = 1 / mean(diff(app.time));  % 采样频率
    L = length(pressure);       % 数据长度
    Y = fft(pressure);          % 快速傅里叶变换
    P2 = abs(Y / L);            % 双侧频谱
    P1 = P2(1:L/2+1);           % 单侧频谱
    P1(2:end-1) = 2 * P1(2:end-1);
    f = fs * (0:(L/2)) / L;     % 频率向量
    
    plot(app.UIAxes_RU, f, P1);
    title(app.UIAxes_RU, '频域波形 - 声压');
    xlabel(app.UIAxes_RU, '频率 (Hz)');
    ylabel(app.UIAxes_RU, '幅值');
    app.label_REsample = 0;
    end
    if num2 == 1
    app.TestData = app.dataWithoutMean_fin;
    data = app.TestData;
                % 提取时间、声压和加速度数据
    app.time = data(:, 1);             % 第1列为时间（秒）
    pressure = data(:, 2);         % 第2列为声压（帕）
    acceleration = data(:, 3);     % 第3列为加速度（m/s²）
    
    % 时域波形绘制（在 UIAxes_dataup_T 显示）
    plot(app.UIAxes_LU, app.time, pressure);
    title(app.UIAxes_LU, '时域波形 - 声压');
    xlabel(app.UIAxes_LU, '时间 (s)');
    ylabel(app.UIAxes_LU, '声压 (Pa)');
    
    % 频域波形绘制（在 UIAxes_dataup_F 显示）
    fs = 1 / mean(diff(app.time));  % 采样频率
    L = length(pressure);       % 数据长度
    Y = fft(pressure);          % 快速傅里叶变换
    P2 = abs(Y / L);            % 双侧频谱
    P1 = P2(1:L/2+1);           % 单侧频谱
    P1(2:end-1) = 2 * P1(2:end-1);
    f = fs * (0:(L/2)) / L;     % 频率向量
    
    plot(app.UIAxes_RU, f, P1);
    title(app.UIAxes_RU, '频域波形 - 声压');
    xlabel(app.UIAxes_RU, '频率 (Hz)');
    ylabel(app.UIAxes_RU, '幅值');
    app.label_REmean = 0;
    end
    if num3 == 1
    app.TestData = app.smoothedData_fin;
    data = app.TestData;
                % 提取时间、声压和加速度数据
    app.time = data(:, 1);             % 第1列为时间（秒）
    pressure = data(:, 2);         % 第2列为声压（帕）
    acceleration = data(:, 3);     % 第3列为加速度（m/s²）
    
    % 时域波形绘制（在 UIAxes_dataup_T 显示）
    plot(app.UIAxes_LU, app.time, pressure);
    title(app.UIAxes_LU, '时域波形 - 声压');
    xlabel(app.UIAxes_LU, '时间 (s)');
    ylabel(app.UIAxes_LU, '声压 (Pa)');
    
    % 频域波形绘制（在 UIAxes_dataup_F 显示）
    fs = 1 / mean(diff(app.time));  % 采样频率
    L = length(pressure);       % 数据长度
    Y = fft(pressure);          % 快速傅里叶变换
    P2 = abs(Y / L);            % 双侧频谱
    P1 = P2(1:L/2+1);           % 单侧频谱
    P1(2:end-1) = 2 * P1(2:end-1);
    f = fs * (0:(L/2)) / L;     % 频率向量
    
    plot(app.UIAxes_RU, f, P1);
    title(app.UIAxes_RU, '频域波形 - 声压');
    xlabel(app.UIAxes_RU, '频率 (Hz)');
    ylabel(app.UIAxes_RU, '幅值');
    app.label_Smooth = 0;
    end
        end

        % Value changed function: ResampleSignalButtonPushed_2
        function ResampleSignalButtonPushed_2ValueChanged(app, event)
% 按钮的回调函数
app.label_Smooth = 1;
    inputText = app.text_chuang.Value;
    % 检查输入值的类型，如果是数字则转换为字符串
    if isnumeric(inputText)
        inputText = num2str(inputText);  % 将数字转为字符串
    end
    
    % 去除前后空格
    inputText = strtrim(inputText);
    
    % 将字符串转换为数字
    windowSize = str2double(inputText);

%%
% 检查是否已加载数据
if isempty(app.TestData)
    uialert(app.UIFigure, '未加载数据，请先上传数据', '错误', 'Icon', 'error');
    return;
end

% 检查窗大小是否有效
% windowSize = str2double(app.text_chuang.Value);
if isnan(windowSize) || windowSize <= 0 || mod(windowSize, 1) ~= 0
    uialert(app.UIFigure, '请输入有效的窗大小（正整数）', '错误', 'Icon', 'error');
    return;
end

% 获取时间列和数据列
time = app.TestData{:, 1};  % 第一列是时间
data = app.TestData{:, 2:end};  % 剩余列是信号

% 动态计算采样频率
dt = mean(diff(time));  % 时间间隔（假设等间隔采样）
Fs = 1 / dt;  % 采样频率

% 应用平滑处理
smoothedData = movmean(data, windowSize);

% 更新平滑后的数据到 App 属性
app.smoothedData_fin = smoothedData;

% 在 UIAxes_LD 更新时域图，显示平滑后的第一列信号
plot(app.UIAxes_LD, time, smoothedData(:, 1)); % 第一列信号
title(app.UIAxes_LD, '平滑后的时域信号');
xlabel(app.UIAxes_LD, '时间 (秒)');
ylabel(app.UIAxes_LD, '信号幅值');

% 如果需要，显示频域图
N = length(smoothedData(:, 1));  % 信号长度
f = Fs * (0:(N/2)) / N;  % 频率轴
Y = fft(smoothedData(:, 1));  % 计算 FFT
P2 = abs(Y / N);  % 双边谱
P1 = P2(1:N/2+1);  % 单边谱
P1(2:end-1) = 2 * P1(2:end-1);  % 单边幅值谱

plot(app.UIAxes_RD, f, P1);  % 显示频域谱线
title(app.UIAxes_RD, '平滑后的频域信号');
xlabel(app.UIAxes_RD, '频率 (Hz)');
ylabel(app.UIAxes_RD, '幅度');

% 统计平滑后数据的信息
totalRows = size(smoothedData, 1);        % 数据总行数
maxValues = max(smoothedData);            % 每列最大值
minValues = min(smoothedData);            % 每列最小值

app.smoothedData_fin = [app.TestData{:,1},app.smoothedData_fin];
% 更新文本区域显示信息
app.funtion2_TextArea.Value = sprintf( ...
    '平滑后的数据信息：\n总行数：%d第二列最大值：%.2f，最小值：%.2f\n第三列最大值：%.2f，最小值：%.2f', ...
    totalRows, maxValues(1), minValues(1), maxValues(2), minValues(2) ...
    );
            
        end

        % Value changed function: IIR_Fs
        function IIR_FsValueChanged(app, event)
                     value = app.IIR_Fs.Value;
            app.iir_Fs=str2num(value);
            
        end

        % Value changed function: IIR_Rs
        function IIR_RsValueChanged(app, event)
            value = app.IIR_Rs.Value
            app.iir_Rs=value
        end

        % Value changed function: IIR_Rp
        function IIR_RpValueChanged(app, event)
           value = app.IIR_Rp.Value
           app.iir_Rp=value
        end

        % Value changed function: IIR_Fp
        function IIR_FpValueChanged(app, event)
            value = app.IIR_Fp.Value
            app.iir_Fp=str2num(value)
        end

        % Value changed function: FIR_Fs
        function FIR_FsValueChanged(app, event)
            value = app.FIR_Fs.Value;
            app.fir_Fs=str2num(value);
        end

        % Value changed function: FIR_Rs
        function FIR_RsValueChanged(app, event)
            value = app.FIR_Rs.Value;
            app.fir_Rs=str2num(value);
        end

        % Value changed function: FIR_Rp
        function FIR_RpValueChanged(app, event)
            value = app.FIR_Rp.Value;
            app.fir_Rp=str2num(value);
        end

        % Value changed function: FIR_Fp
        function FIR_FpValueChanged(app, event)
            value = app.FIR_Fp.Value;
            app.fir_Fp=str2num(value);
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            [file, path] = uigetfile({'*.xlsx;*.csv;*.txt', 'Test Signal Files (*.xlsx, *.csv, *.txt)'}, 'Select a Test Signal File');
if isequal(file, 0)
    % 未选择文件，显示提示
    app.Label_first.Text = '未选择文件';
else
    % % 更新 Label 显示文件路径
    app.Label_first.Text = fullfile(path, file);

    % 读取文件内容，根据文件类型自动解析
    [~, ~, ext] = fileparts(file);
    switch lower(ext)
        case '.xlsx'
            data = readtable(fullfile(path, file));
        case '.csv'
            data = readtable(fullfile(path, file));
        case '.txt'
            data = readtable(fullfile(path, file), 'Delimiter', '\t');
        otherwise
            uialert(app.UIFigure, '文件格式不支持', '错误', 'Icon', 'error');
            return;
    end
    
    % 移除含有中文字符的行（示例，假设检查整个表）
    % data = data(~any(varfun(@(x) iscellstr(x) & contains(x, '[^\x00-\x7F]', 'once'), data), 2), :);
    
    % 保存数据到 App 属性中以供后续处理
    app.TestData_system = data;%testdata_system表示的是原始的数据，但是后续可能被更新
    
    % 提取时间、激励信号和响应信号
    app.time = data{:, 1};             % 第1列为时间（秒）
    pressure = data{:, 2};        
    acceleration = data{:, 3};     
end
        end

        % Button pushed function: plotFrequencyAndTransfer
        function plotFrequencyAndTransferPushed(app, event)
   % 检查是否有上传的数据
if isempty(app.TestData_system)
    uialert(app.UIFigure, '请先上传测试信号文件！', '警告', 'Icon', 'warning');
    return;
end

% 获取时间、激励信号和响应信号
time_fun = app.time;               % 时间（秒）
input_signal = app.TestData_system{:, 2};  % 激励信号
output_signal = app.TestData_system{:, 3}; % 响应信号

% 检查数据是否为空
if isempty(time_fun) || isempty(input_signal) || isempty(output_signal)
    uialert(app.UIFigure, '数据不完整，请检查测试文件！', '错误', 'Icon', 'error');
    return;
end

% 时间差（采样周期）
dt = mean(diff(time_fun));
fs = 1 / dt;  % 采样频率

% FFT 分析：激励信号
L = length(input_signal);
f = fs * (0:(L/2)) / L;  % 频率向量
fft_input = fft(input_signal);  % 激励信号 FFT
fft_output = fft(output_signal);  % 响应信号 FFT

% 幅频特性（Magnitude）
magnitude_input = abs(fft_input / L);  
magnitude_output = abs(fft_output / L);  
magnitude_transfer = magnitude_output ./ magnitude_input;

% 单边幅频特性
magnitude_transfer = magnitude_transfer(1:L/2+1);
magnitude_transfer(2:end-1) = 2 * magnitude_transfer(2:end-1);

% 相频特性（Phase）
phase_input = angle(fft_input);
phase_output = angle(fft_output);
phase_transfer = phase_output - phase_input;

% 单边相频特性
phase_transfer = phase_transfer(1:L/2+1);

% 绘制系统传递函数（幅频特性和相频特性）
fig = figure('Name', '系统传递函数', 'NumberTitle', 'off', ...
             'Position', [100, 100, 800, 400]);  % 弹窗属性

% 绘制幅频特性
subplot(2, 1, 1);  % 上方显示幅频特性
plot(f, magnitude_transfer, 'b', 'LineWidth', 1.5);
title('系统传递函数 - 幅频特性');
xlabel('频率 (Hz)');
ylabel('幅值');
grid on;

% 绘制相频特性
subplot(2, 1, 2);  % 下方显示相频特性
plot(f, unwrap(phase_transfer), 'r', 'LineWidth', 1.5);
title('系统传递函数 - 相频特性');
xlabel('频率 (Hz)');
ylabel('相位 (弧度)');
grid on;
        end

        % Button pushed function: frequence_show
        function frequence_showButtonPushed(app, event)
    if isempty(app.TestData_system)
        uialert(app.UIFigure, '请先上传测试信号文件！', '警告', 'Icon', 'warning');
        return;
    end

    % 获取时间、激励信号和响应信号
    time_fun = app.time;               % 时间（秒）
    input_signal = app.TestData_system{:, 2};  % 激励信号
    output_signal = app.TestData_system{:, 3}; % 响应信号

    % 检查数据是否为空
    if isempty(time_fun) || isempty(input_signal) || isempty(output_signal)
        uialert(app.UIFigure, '数据不完整，请检查测试文件！', '错误', 'Icon', 'error');
        return;
    end

    % 时间差（采样周期）
    dt = mean(diff(time_fun));
    fs = 1 / dt;  % 采样频率

    % FFT 分析：激励信号
    L = length(input_signal);
    f = fs * (0:(L/2)) / L;  % 频率向量
    fft_input = fft(input_signal);  % 激励信号 FFT
    P2_input = abs(fft_input / L);  % 双边频谱
    P1_input = P2_input(1:L/2+1);   % 单边频谱
    P1_input(2:end-1) = 2 * P1_input(2:end-1); 

    % FFT 分析：响应信号
    fft_output = fft(output_signal);  % 响应信号 FFT
    P2_output = abs(fft_output / L);  % 双边频谱
    P1_output = P2_output(1:L/2+1);   % 单边频谱
    P1_output(2:end-1) = 2 * P1_output(2:end-1);

    % 绘制激励信号频域图
    plot(app.UIAxes_LD, f, P1_input, 'b', 'LineWidth', 1.5);
    title(app.UIAxes_LD, '激励信号的频域图');
    xlabel(app.UIAxes_LD, '频率 (Hz)');
    ylabel(app.UIAxes_LD, '幅值');
    grid(app.UIAxes_LD, 'on');

    % 绘制响应信号频域图
    plot(app.UIAxes_RD, f, P1_output, 'r', 'LineWidth', 1.5);
    title(app.UIAxes_RD, '响应信号的频域图');
    xlabel(app.UIAxes_RD, '频率 (Hz)');
    ylabel(app.UIAxes_RD, '幅值');
    grid(app.UIAxes_RD, 'on');
        end

        % Button pushed function: time_show
        function time_showButtonPushed(app, event)
if isempty(app.TestData_system)
    uialert(app.UIFigure, '请先上传测试信号文件！', '警告', 'Icon', 'warning');
    return;
end

% 获取时间、激励信号和响应信号
time_fun = app.time;               % 时间（秒）
input_signal = app.TestData_system{:, 2};  % 激励信号
output_signal = app.TestData_system{:, 3}; % 响应信号

% 检查数据是否为空
if isempty(time_fun) || isempty(input_signal) || isempty(output_signal)
    uialert(app.UIFigure, '数据不完整，请检查测试文件！', '错误', 'Icon', 'error');
    return;
end

% 绘制激励信号的时域波形
plot(app.UIAxes_LD, time_fun, input_signal, 'b', 'LineWidth', 1.5);
title(app.UIAxes_LD, '激励信号的时域波形');
xlabel(app.UIAxes_LD, '时间 (s)');
ylabel(app.UIAxes_LD, '幅值');
grid(app.UIAxes_LD, 'on');

% 绘制响应信号的时域波形
plot(app.UIAxes_RD, time_fun, output_signal, 'r', 'LineWidth', 1.5);
title(app.UIAxes_RD, '响应信号的时域波形');
xlabel(app.UIAxes_RD, '时间 (s)');
ylabel(app.UIAxes_RD, '幅值');
grid(app.UIAxes_RD, 'on');
        end

        % Callback function
        function gonglvpuButtonPushed(app, event)
if isempty(app.TestData)
    uialert(app.UIFigure, '请先上传测试信号文件！', '警告', 'Icon', 'warning');
    return;
end

% 提取第二列数据（声压）
pressure = app.TestData{:, 2};  % 第2列为声压（帕）

% 检查数据是否为空
if isempty(pressure)
    uialert(app.UIFigure, '声压数据为空，请检查上传的文件！', '错误', 'Icon', 'error');
    return;
end

% 计算采样频率
dt = mean(diff(app.time));  % 时间差（采样周期）
fs = 1 / dt;  % 采样频率

% 快速傅里叶变换 (FFT)
L = length(pressure);         % 数据长度
Y = fft(pressure);            % 快速傅里叶变换
P2 = abs(Y / L).^2;           % 双侧功率谱
P1 = P2(1:L/2+1);             % 单侧功率谱
P1(2:end-1) = 2 * P1(2:end-1); 
f = fs * (0:(L/2)) / L;       % 频率向量

% 绘制功率谱
figure('Name', '功率谱', 'NumberTitle', 'off');
plot(f, P1, 'b', 'LineWidth', 1.5);
grid on;
title('声压功率谱');
xlabel('频率 (Hz)');
ylabel('功率');
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
          if isempty(app.TestData)
    uialert(app.UIFigure, '请先上传测试信号文件！', '警告', 'Icon', 'warning');
    return;
end

% 提取声压和加速度数据
pressure = app.TestData{:, 2};      % 第2列为声压（帕）
acceleration = app.TestData{:, 3}; % 第3列为加速度（m/s²）

% 检查数据是否为空
if isempty(pressure) || isempty(acceleration)
    uialert(app.UIFigure, '数据不完整，请检查上传的文件！', '错误', 'Icon', 'error');
    return;
end

% 计算采样频率
dt = mean(diff(app.time));  % 时间差（采样周期）
fs = 1 / dt;  % 采样频率

% 定义 PSD 参数
window_length = round(length(pressure) / 8); % 分段长度
overlap = round(window_length / 2);         % 重叠数
nfft = max(256, 2^nextpow2(window_length)); % 频率分辨率

% 功率谱密度：声压
[pxx_pressure, f_pressure] = pwelch(pressure, window_length, overlap, nfft, fs);

% 功率谱密度：加速度
[pxx_acceleration, f_acceleration] = pwelch(acceleration, window_length, overlap, nfft, fs);

% 在 UIAxes_LD 绘制声压功率谱密度
plot(app.UIAxes_LD, f_pressure, 10*log10(pxx_pressure), 'b', 'LineWidth', 1.5);
title(app.UIAxes_LD, '声压功率谱密度');
xlabel(app.UIAxes_LD, '频率 (Hz)');
ylabel(app.UIAxes_LD, 'PSD (dB/Hz)');
grid(app.UIAxes_LD, 'on');

% 在 UIAxes_RD 绘制加速度功率谱密度
plot(app.UIAxes_RD, f_acceleration, 10*log10(pxx_acceleration), 'r', 'LineWidth', 1.5);
title(app.UIAxes_RD, '加速度功率谱密度');
xlabel(app.UIAxes_RD, '频率 (Hz)');
ylabel(app.UIAxes_RD, 'PSD (dB/Hz)');
grid(app.UIAxes_RD, 'on');  
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
if isempty(app.TestData)
    uialert(app.UIFigure, '请先上传测试信号文件！', '警告', 'Icon', 'warning');
    return;
end

% 提取声压和加速度数据
pressure = app.TestData{:, 2};      % 第2列为声压（帕）
acceleration = app.TestData{:, 3}; % 第3列为加速度（m/s²）

% 检查数据是否为空
if isempty(pressure) || isempty(acceleration)
    uialert(app.UIFigure, '数据不完整，请检查上传的文件！', '错误', 'Icon', 'error');
    return;
end

% 自相关计算：声压
auto_corr_pressure = xcorr(pressure, 'coeff');  % 归一化自相关
lags_pressure = (-length(pressure)+1):(length(pressure)-1);  % 滞后

% 自相关计算：加速度
auto_corr_acceleration = xcorr(acceleration, 'coeff');  % 归一化自相关
lags_acceleration = (-length(acceleration)+1):(length(acceleration)-1);  % 滞后

% 时间滞后的步长
dt = mean(diff(app.time));  % 时间差
time_lags_pressure = lags_pressure * dt;  % 声压对应的时间滞后
time_lags_acceleration = lags_acceleration * dt;  % 加速度对应的时间滞后

% 在 UIAxes_LD 绘制声压的自相关函数
plot(app.UIAxes_LD, time_lags_pressure, auto_corr_pressure, 'b', 'LineWidth', 1.5);
title(app.UIAxes_LD, '声压的自相关函数');
xlabel(app.UIAxes_LD, '滞后时间 (s)');
ylabel(app.UIAxes_LD, '归一化自相关值');
grid(app.UIAxes_LD, 'on');

% 在 UIAxes_RD 绘制加速度的自相关函数
plot(app.UIAxes_RD, time_lags_acceleration, auto_corr_acceleration, 'r', 'LineWidth', 1.5);
title(app.UIAxes_RD, '加速度的自相关函数');
xlabel(app.UIAxes_RD, '滞后时间 (s)');
ylabel(app.UIAxes_RD, '归一化自相关值');
grid(app.UIAxes_RD, 'on');
        end

        % Button pushed function: EvaluateSoundQualityCallback
        function EvaluateSoundQualityCallbackPushed(app, event)
      
    % 检查是否有上传的数据
    if isempty(app.TestData)
        uialert(app.UIFigure, '请先上传测试信号文件！', '警告', 'Icon', 'warning');
        return;
    end
    
    % 提取声压数据（假设第2列是声压）
    pressure = app.TestData{:, 2};  % 声压 (Pa)
    fs = 1 / mean(diff(app.time)); % 采样频率
    
    % 检查数据是否为空
    if isempty(pressure)
        uialert(app.UIFigure, '声压数据为空，请检查文件内容！', '错误', 'Icon', 'error');
        return;
    end
    
    % ---- 计算声品质客观评价指标 ---- %
    % 响度 (Loudness)
    % 使用近似计算方法（或导入专用工具箱进行精确计算）
    loudness = mean(abs(pressure)) * 1e6; % 简单比例换算作为示例

    % 粗糙度 (Roughness)
    % 计算为快速变化的短时变化率
    roughness = sum(abs(diff(pressure))) / length(pressure); 
    
    % 尖锐度 (Sharpness)
    % 根据声压平方计算加权函数示例（假设信号能量集中在中频）
    sharpness = sum(pressure.^2 .* (1:length(pressure))') / sum(pressure.^2); 
    
    % 波动度 (Fluctuation)
    % 采用能量包络提取和低频分析
    fluctuation = std(abs(pressure)); 
    
    % 格式化结果为字符串
    sound_quality_results = sprintf(['声品质客观评价结果：\n' ...
        '- 响度 (Loudness): %.2f (sones)\n' ...
        '- 粗糙度 (Roughness): %.2f\n' ...
        '- 尖锐度 (Sharpness): %.2f\n' ...
        '- 波动度 (Fluctuation): %.2f\n'], ...
        loudness, roughness, sharpness, fluctuation);
    
    % ---- 显示结果到 TextArea ---- %
    app.TextArea.Value = sound_quality_results;
        end

        % Button pushed function: AnalyzeThirdOctaveAWeightedCallback
        function AnalyzeThirdOctaveAWeightedCallbackPushed(app, event)
 label_1 = app.labeljisuan;
 label_2 = app.labelbeipin;
            
 if label_1 == 1
      if label_2 == 1
    % 检查是否有上传的数据
    if isempty(app.TestData)
        uialert(app.UIFigure, '请先上传测试信号文件！', '警告', 'Icon', 'warning');
        return;
    end

    % 提取声压数据和采样频率
    pressure = app.TestData{:, 2};  % 假设第2列是声压 (Pa)
    fs = 1 / mean(diff(app.time)); % 采样频率

    % 检查数据是否为空
    if isempty(pressure)
        uialert(app.UIFigure, '声压数据为空，请检查文件内容！', '错误', 'Icon', 'error');
        return;
    end
    
    % ---- 1/3 倍频程分析 ---- %
    % 设置 1/3 倍频程中心频率（ISO 266 标准）
    center_frequencies = [12.5, 16, 20, 25, 31.5, 40, 50, 63, 80, 100, ...
                          125, 160, 200, 250, 315, 400, 500, 630, 800, ...
                          1000, 1250, 1600, 2000, 2500, 3150, 4000, ...
                          5000, 6300, 8000, 10000, 12500, 16000, 20000]; % 单位 Hz
                      
    % A 计权修正值（以 dB 为单位）
    a_weighting_correction = [-63.4, -56.7, -50.5, -44.7, -39.4, -34.6, -30.2, ...
                               -26.2, -22.5, -19.1, -16.1, -13.4, -10.9, ...
                               -8.6, -6.6, -4.8, -3.2, -1.9, -0.8, 0, ...
                               -0.8, -1.9, -3.2, -4.8, -6.6, -8.6, ...
                               -10.9, -13.4, -16.1, -19.1, -22.5, -26.2, ...
                               -30.2];

    % 初始化存储 1/3 倍频程 A 计权结果
    num_bands = length(center_frequencies);
    band_levels = zeros(1, num_bands);  % 存储每个频段的级别

    for i = 1:num_bands
        % 计算每个频带的带通滤波器
        f_low = center_frequencies(i) / 2^(1/6);  % 下限频率
        f_high = center_frequencies(i) * 2^(1/6); % 上限频率

        % 检查频率范围是否在奈奎斯特频率内
        if f_high > fs / 2
            continue;  % 跳过超出采样限制的频段
        end

        % 归一化频率
        Wn = [f_low, f_high] / (fs / 2);  % 将频率归一化到 (0, 1)

        % 创建带通滤波器
        [b, a] = butter(4, Wn);  % 4 阶带通滤波器
        filtered_signal = filtfilt(b, a, pressure); % 滤波后的信号

        % 计算声压级 (以 dB 为单位)
        rms_value = sqrt(mean(filtered_signal.^2));  % 均方根值
        band_levels(i) = 20 * log10(rms_value / 20e-6); % 参考声压 20 μPa
    end

    % 应用 A 计权修正
    a_weighted_levels = band_levels + a_weighting_correction;

    % ---- 绘制图表：柱状图 + 折线图 ---- %
    figure('Name', '1/3 倍频程 A 计权声压级分析', 'NumberTitle', 'off', ...
           'Position', [100, 100, 1200, 500]);  % 加宽窗口

    % 绘制柱状图
    bar(center_frequencies, a_weighted_levels, 'BarWidth', 8, ...
        'FaceColor', [0.2, 0.6, 0.8]); % 设置宽柱子
    hold on;

    % 绘制折线图
    plot(center_frequencies, a_weighted_levels, '-o', 'LineWidth', 2, ...
         'Color', [0.55, 0.33, 0.31], 'MarkerFaceColor', [1, 0.5, 0.1]);

    % 图形美化
    set(gca, 'XScale', 'log');  % 使用对数坐标表示频率
    xlabel('中心频率 (Hz)', 'FontSize', 12);
    ylabel('A 计权声压级 (dB)', 'FontSize', 12);
    title('1/3 倍频程 A 计权声压级分析', 'FontSize', 14);
    grid on;
    xlim([min(center_frequencies) max(center_frequencies)]);
    legend({'柱状图', '折线图'}, 'FontSize', 12);
    hold off;

      end
  end

  if label_1 == 2
      if label_2 ==1
    % 检查是否有上传的数据
    if isempty(app.TestData)
        uialert(app.UIFigure, '请先上传测试信号文件！', '警告', 'Icon', 'warning');
        return;
    end

    % 提取声压数据和采样频率
    pressure = app.TestData{:, 2};  % 假设第2列是声压 (Pa)
    fs = 1 / mean(diff(app.time)); % 采样频率

    % 检查数据是否为空
    if isempty(pressure)
        uialert(app.UIFigure, '声压数据为空，请检查文件内容！', '错误', 'Icon', 'error');
        return;
    end
    
    % ---- 1/3 倍频程分析 ---- %
    % 设置 1/3 倍频程中心频率（ISO 266 标准）
    center_frequencies = [12.5, 16, 20, 25, 31.5, 40, 50, 63, 80, 100, ...
                          125, 160, 200, 250, 315, 400, 500, 630, 800, ...
                          1000, 1250, 1600, 2000, 2500, 3150, 4000, ...
                          5000, 6300, 8000, 10000, 12500, 16000, 20000]; % 单位 Hz
                      
    % B 计权修正值（以 dB 为单位）
    b_weighting_correction = [-63.4, -56.7, -50.5, -44.7, -39.4, -34.6, -30.2, ...
                               -26.2, -22.5, -19.1, -16.1, -13.4, -10.9, ...
                               -8.6, -6.6, -4.8, -3.2, -1.2, 0, 1.1, ...
                               1.6, 1.2, 0, -1.2, -2.5, -4.3, -6.6, ...
                               -9.3, -12.4, -15.8, -19.5, -23.6, -28.0]; 

    % 初始化存储 1/3 倍频程 B 计权结果
    num_bands = length(center_frequencies);
    band_levels = zeros(1, num_bands);  % 存储每个频段的级别

    for i = 1:num_bands
        % 计算每个频带的带通滤波器
        f_low = center_frequencies(i) / 2^(1/6);  % 下限频率
        f_high = center_frequencies(i) * 2^(1/6); % 上限频率

        % 检查频率范围是否在奈奎斯特频率内
        if f_high > fs / 2
            continue;  % 跳过超出采样限制的频段
        end

        % 归一化频率
        Wn = [f_low, f_high] / (fs / 2);  % 将频率归一化到 (0, 1)

        % 创建带通滤波器
        [b, a] = butter(4, Wn);  % 4 阶带通滤波器
        filtered_signal = filtfilt(b, a, pressure); % 滤波后的信号

        % 计算声压级 (以 dB 为单位)
        rms_value = sqrt(mean(filtered_signal.^2));  % 均方根值
        band_levels(i) = 20 * log10(rms_value / 20e-6); % 参考声压 20 μPa
    end

    % 应用 B 计权修正
    b_weighted_levels = band_levels + b_weighting_correction;

    % ---- 绘制图表：柱状图 + 折线图 ---- %
    figure('Name', '1/3 倍频程 B 计权声压级分析', 'NumberTitle', 'off', ...
           'Position', [100, 100, 1200, 500]);  % 加宽窗口

    % 绘制柱状图
    bar(center_frequencies, b_weighted_levels, 'BarWidth', 0.9, ...
        'FaceColor', [0.2, 0.6, 0.8]); % 设置宽柱子
    hold on;

    % 绘制折线图
    plot(center_frequencies, b_weighted_levels, '-o', 'LineWidth', 2, ...
         'Color', [0.55, 0.33, 0.31], 'MarkerFaceColor', [1, 0.5, 0.1]);

    % 图形美化
    set(gca, 'XScale', 'log');  % 使用对数坐标表示频率
    xlabel('中心频率 (Hz)', 'FontSize', 12);
    ylabel('B 计权声压级 (dB)', 'FontSize', 12);
    title('1/3 倍频程 B 计权声压级分析', 'FontSize', 14);
    grid on;
    xlim([min(center_frequencies) max(center_frequencies)]);
    legend({'柱状图', '折线图'}, 'FontSize', 12);
    hold off;

      end
  end
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '上传数据';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '上传电机文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '上传激励文件';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_LU = uiaxes(app.GridLayout);
            title(app.UIAxes_LU, 'Title')
            xlabel(app.UIAxes_LU, 'X')
            ylabel(app.UIAxes_LU, 'Y')
            zlabel(app.UIAxes_LU, 'Z')
            app.UIAxes_LU.Tag = 'UIAxes_LU';
            app.UIAxes_RU = uiaxes(app.GridLayout);
            title(app.UIAxes_RU, 'Title')
            xlabel(app.UIAxes_RU, 'X')
            ylabel(app.UIAxes_RU, 'Y')
            zlabel(app.UIAxes_RU, 'Z')
            app.UIAxes_RU.Tag = 'UIAxes_RU';
            app.UIAxes_LD = uiaxes(app.GridLayout);
            title(app.UIAxes_LD, 'Title')
            xlabel(app.UIAxes_LD, 'X')
            ylabel(app.UIAxes_LD, 'Y')
            zlabel(app.UIAxes_LD, 'Z')
            app.UIAxes_LD.Tag = 'UIAxes_LD';
            app.UIAxes_RD = uiaxes(app.GridLayout);
            title(app.UIAxes_RD, 'Title')
            xlabel(app.UIAxes_RD, 'X')
            ylabel(app.UIAxes_RD, 'Y')
            zlabel(app.UIAxes_RD, 'Z')
            app.UIAxes_RD.Tag = 'UIAxes_RD';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '截取数据';
            app.TrimDataButtonPushed = uibutton(app.Tab, 'push');
            app.TrimDataButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @TrimDataButtonPushedPushed, true);
            app.TrimDataButtonPushed.Text = '开始截取';
            app.SaveTrimmedDataButtonPushed = uibutton(app.Tab, 'push');
            app.SaveTrimmedDataButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @SaveTrimmedDataButtonPushedPushed, true);
            app.SaveTrimmedDataButtonPushed.Text = '保存截取';
            app.Label_10 = uilabel(app.Tab);
            app.Label_10.Text = '开始时间/s';
            app.starttime = uieditfield(app.Tab, 'numeric');
            app.Label_11 = uilabel(app.Tab);
            app.Label_11.Text = '结束时间/s';
            app.endtime = uieditfield(app.Tab, 'numeric');
            app.Label_12 = uilabel(app.Tab);
            app.Label_12.Text = '按点数截/个 ';
            app.n_sample_text = uieditfield(app.Tab, 'numeric');
            app.Label_13 = uilabel(app.Tab);
            app.Label_13.Text = '截取信息';
            app.showcutTextArea = uitextarea(app.Tab);
            app.Button_2 = uibutton(app.Tab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '更新原数据';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '预处理';
            app.ResampleSignalButtonPushed = uibutton(app.Tab_2, 'state');
            app.ResampleSignalButtonPushed.ValueChangedFcn = createCallbackFcn(app, @ResampleSignalButtonPushedValueChanged, true);
            app.ResampleSignalButtonPushed.Text = '开始重采样';
            app.RemoveMeanButtonCallback = uibutton(app.Tab_2, 'push');
            app.RemoveMeanButtonCallback.ButtonPushedFcn = createCallbackFcn(app, @RemoveMeanButtonCallbackPushed, true);
            app.RemoveMeanButtonCallback.Text = '去均值';
            app.Button = uibutton(app.Tab_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '更新原数据';
            app.DownloadResampledDataButtonPushed = uibutton(app.Tab_2, 'push');
            app.DownloadResampledDataButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @DownloadResampledDataButtonPushedPushed, true);
            app.DownloadResampledDataButtonPushed.Text = '下载数据';
            app.ResampleSignalButtonPushed_2 = uibutton(app.Tab_2, 'state');
            app.ResampleSignalButtonPushed_2.ValueChangedFcn = createCallbackFcn(app, @ResampleSignalButtonPushed_2ValueChanged, true);
            app.ResampleSignalButtonPushed_2.Text = '平滑处理';
            app.Label_14 = uilabel(app.Tab_2);
            app.Label_14.Text = '重采样数值（时间）';
            app.EditField_ccy = uieditfield(app.Tab_2, 'numeric');
            app.Label_15 = uilabel(app.Tab_2);
            app.Label_15.Text = '窗口长度';
            app.text_chuang = uieditfield(app.Tab_2, 'numeric');
            app.text_chuang.Tag = 'text_chuang';
            app.Label_16 = uilabel(app.Tab_2);
            app.Label_16.Text = {'处理'; '信号'; '信息'};
            app.funtion2_TextArea = uitextarea(app.Tab_2);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '滤波设计';
            app.GridLayout2 = uigridlayout(app.Tab_3);
            app.Filter_amplitude = uiaxes(app.GridLayout2);
            title(app.Filter_amplitude, '滤波器幅频特性')
            xlabel(app.Filter_amplitude, 'f/Hz')
            ylabel(app.Filter_amplitude, '幅值(dB)')
            zlabel(app.Filter_amplitude, 'Z')
            app.Filter_phase = uiaxes(app.GridLayout2);
            title(app.Filter_phase, '滤波器相频特性')
            xlabel(app.Filter_phase, 'f/Hz')
            ylabel(app.Filter_phase, '相位(\pi)')
            zlabel(app.Filter_phase, 'Z')
            app.IIRPanel = uipanel(app.GridLayout2);
            app.IIRPanel.Title = 'IIR';
            app.Label_17 = uilabel(app.IIRPanel);
            app.Label_17.Text = '滤波器型号';
            app.IIR_filterchoose = uidropdown(app.IIRPanel);
            app.IIR_filterchoose.Items = {'1.巴特沃斯滤波器', '2.切比雪夫Ⅰ型', '3.切比雪夫Ⅱ型', '4.椭圆滤波器'};
            app.IIR_filterchoose.Value = '1.巴特沃斯滤波器';
            app.DropDown_3Label = uilabel(app.IIRPanel);
            app.DropDown_3Label.Text = '滤波器性质';
            app.IIR_choose = uidropdown(app.IIRPanel);
            app.IIR_choose.Items = {'1.低通', '2.高通', '3.带通', '4.带阻'};
            app.IIR_choose.Value = '1.低通';
            app.Label_18 = uilabel(app.IIRPanel);
            app.Label_18.Text = '设计方法';
            app.IIR_method = uidropdown(app.IIRPanel);
            app.IIR_method.Items = {'1.脉冲响应不变法', '2.双线性变换法'};
            app.IIR_method.Value = '1.脉冲响应不变法';
            app.FsLabel = uilabel(app.IIRPanel);
            app.FsLabel.Text = '阻带最小衰减(Rs/dB)';
            app.IIR_Rs = uieditfield(app.IIRPanel, 'numeric');
            app.IIR_Rs.ValueChangedFcn = createCallbackFcn(app, @IIR_RsValueChanged, true);
            app.RpdBEditField_2Label = uilabel(app.IIRPanel);
            app.RpdBEditField_2Label.Text = '通带最大衰减(Rp/dB)';
            app.IIR_Rp = uieditfield(app.IIRPanel, 'numeric');
            app.IIR_Rp.ValueChangedFcn = createCallbackFcn(app, @IIR_RpValueChanged, true);
            app.FpHzEditFieldLabel = uilabel(app.IIRPanel);
            app.FpHzEditFieldLabel.Text = '通带截止频率(Fp/Hz)';
            app.IIR_Fp = uieditfield(app.IIRPanel, 'text');
            app.IIR_Fp.ValueChangedFcn = createCallbackFcn(app, @IIR_FpValueChanged, true);
            app.IIR_Fp.Value = '0';
            app.FpHzEditField_2Label = uilabel(app.IIRPanel);
            app.FpHzEditField_2Label.Text = '阻带截止频率(Fs/Hz)';
            app.IIR_Fs = uieditfield(app.IIRPanel, 'text');
            app.IIR_Fs.ValueChangedFcn = createCallbackFcn(app, @IIR_FsValueChanged, true);
            app.IIR_Fs.Value = '0';
            app.FIRPanel = uipanel(app.GridLayout2);
            app.FIRPanel.Title = 'FIR';
            app.DropDown_5Label = uilabel(app.FIRPanel);
            app.DropDown_5Label.Text = '设计方法';
            app.FIR_method = uidropdown(app.FIRPanel);
            app.FIR_method.Items = {'1.窗函数设计法', '2.等波纹最佳逼近法'};
            app.FIR_method.Value = '1.窗函数设计法';
            app.Label_19 = uilabel(app.FIRPanel);
            app.Label_19.Text = '窗类型';
            app.win_choose = uidropdown(app.FIRPanel);
            app.win_choose.Items = {'1.汉宁窗', '2.汉明窗', '3.布莱克曼窗', '4.矩形窗', '5.三角型窗', '6.凯塞窗'};
            app.win_choose.Value = '1.汉宁窗';
            app.DropDown_7Label = uilabel(app.FIRPanel);
            app.DropDown_7Label.Text = '滤波器性质';
            app.FIR_choose = uidropdown(app.FIRPanel);
            app.FIR_choose.Items = {'1.低通', '2.高通', '3.带通', '4.带阻'};
            app.FIR_choose.Value = '1.低通';
            app.RsdBEditFieldLabel = uilabel(app.FIRPanel);
            app.RsdBEditFieldLabel.Text = '阻带最小衰减(Rs/dB)';
            app.FIR_Rs = uieditfield(app.FIRPanel, 'numeric');
            app.FIR_Rs.ValueChangedFcn = createCallbackFcn(app, @FIR_RsValueChanged, true);
            app.RpdBEditFieldLabel = uilabel(app.FIRPanel);
            app.RpdBEditFieldLabel.Text = '通带最大衰减(Rp/dB)';
            app.FIR_Rp = uieditfield(app.FIRPanel, 'numeric');
            app.FIR_Rp.ValueChangedFcn = createCallbackFcn(app, @FIR_RpValueChanged, true);
            app.FpHzEditFieldLabel_2 = uilabel(app.FIRPanel);
            app.FpHzEditFieldLabel_2.Text = '通带截止频率(Fp/Hz)';
            app.FIR_Fp = uieditfield(app.FIRPanel, 'text');
            app.FIR_Fp.ValueChangedFcn = createCallbackFcn(app, @FIR_FpValueChanged, true);
            app.FIR_Fp.Value = '0';
            app.FsHzEditFieldLabel = uilabel(app.FIRPanel);
            app.FsHzEditFieldLabel.Text = '阻带截止频率(Fs/Hz)';
            app.FIR_Fs = uieditfield(app.FIRPanel, 'text');
            app.FIR_Fs.ValueChangedFcn = createCallbackFcn(app, @FIR_FsValueChanged, true);
            app.FIR_Fs.Value = '0';
            app.Switch = uiswitch(app.GridLayout2, 'toggle');
            app.Switch.Items = {'IIR', 'FIR'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'IIR';
            app.get_filter = uibutton(app.GridLayout2, 'push');
            app.get_filter.ButtonPushedFcn = createCallbackFcn(app, @get_filterButtonPushed, true);
            app.get_filter.Text = '创建';
            app.ifok = uibutton(app.GridLayout2, 'push');
            app.ifok.ButtonPushedFcn = createCallbackFcn(app, @ifokButtonPushed, true);
            app.ifok.Text = '确定参数';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '信号处理';
            app.Button_7 = uibutton(app.Tab_6, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '信号自相关分析';
            app.Button_8 = uibutton(app.Tab_6, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '信号功率谱分析';
            app.plotFrequencyAndTransfer = uibutton(app.Tab_6, 'push');
            app.plotFrequencyAndTransfer.ButtonPushedFcn = createCallbackFcn(app, @plotFrequencyAndTransferPushed, true);
            app.plotFrequencyAndTransfer.Text = '传递函数分析';
            app.frequence_show = uibutton(app.Tab_6, 'push');
            app.frequence_show.ButtonPushedFcn = createCallbackFcn(app, @frequence_showButtonPushed, true);
            app.frequence_show.Text = '显示激励频谱图';
            app.time_show = uibutton(app.Tab_6, 'push');
            app.time_show.ButtonPushedFcn = createCallbackFcn(app, @time_showButtonPushed, true);
            app.time_show.Text = '显示激励时域图';
            app.Label_20 = uilabel(app.Tab_6);
            app.Label_20.Text = {'相关'; '信息'; '显示'};
            app.TextArea_2 = uitextarea(app.Tab_6);
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '噪声信号分析';
            app.Label_22 = uilabel(app.Tab_5);
            app.Label_22.Text = '主要信息显示';
            app.TextArea = uitextarea(app.Tab_5);
            app.Label_21 = uilabel(app.Tab_5);
            app.Label_21.Text = '计权声压选项';
            app.DropDown = uidropdown(app.Tab_5);
            app.DropDown.Items = {'A计权声压级', 'B计权声压级', 'C计权声压级', 'D计权声压级'};
            app.DropDown.Value = 'A计权声压级';
            app.DropDown_2Label = uilabel(app.Tab_5);
            app.DropDown_2Label.Text = '倍频程选项';
            app.DropDown_2 = uidropdown(app.Tab_5);
            app.DropDown_2.Items = {'1/3倍频程', '1/6倍频程', '1/9倍频程', '1/12倍频程'};
            app.DropDown_2.Value = '1/3倍频程';
            app.EvaluateSoundQualityCallback = uibutton(app.Tab_5, 'push');
            app.EvaluateSoundQualityCallback.ButtonPushedFcn = createCallbackFcn(app, @EvaluateSoundQualityCallbackPushed, true);
            app.EvaluateSoundQualityCallback.Text = '噪声信号声品质客观评价分析';
            app.AnalyzeThirdOctaveAWeightedCallback = uibutton(app.Tab_5, 'push');
            app.AnalyzeThirdOctaveAWeightedCallback.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeThirdOctaveAWeightedCallbackPushed, true);
            app.AnalyzeThirdOctaveAWeightedCallback.Text = '开始相关分析';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_first = uilabel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motor3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end